classdef ea_sweetspotexplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ToolsMenu                       matlab.ui.container.Menu
        RefreshInformationfromLeadGroupprojectMenu  matlab.ui.container.Menu
        RecreatevisualizationfigureMenu  matlab.ui.container.Menu
        SetseedforrandomnumbergeneratorMenu  matlab.ui.container.Menu
        CrossvalidationPredictionPanel  matlab.ui.container.Panel
        ShowImportedModelCheckBox       matlab.ui.control.CheckBox
        SubcohortsDropDown_2            matlab.ui.control.DropDown
        SubcohortsDropDown_2Label       matlab.ui.control.Label
        LoadModelButton                 matlab.ui.control.Button
        LoadPermTestModelButton         matlab.ui.control.Button
        PermuteWithinGroupsCheckBox     matlab.ui.control.CheckBox
        SigModeDropDown                 matlab.ui.control.DropDown
        SigModeDropDownLabel            matlab.ui.control.Label
        ImportedModelShow               matlab.ui.control.ListBox
        BasePredictiononDropDown        matlab.ui.control.DropDown
        BasePredictiononDropDownLabel   matlab.ui.control.Label
        LiveVisualizeCheckBox           matlab.ui.control.CheckBox
        kEditField                      matlab.ui.control.EditField
        kEditFieldLabel                 matlab.ui.control.Label
        StrategyDropDown                matlab.ui.control.DropDown
        StrategyDropDownLabel           matlab.ui.control.Label
        PosthoccorrectforgroupCheckBox  matlab.ui.control.CheckBox
        RunCVButton                     matlab.ui.control.Button
        PredictListBox                  matlab.ui.control.ListBox
        PredictListBoxLabel             matlab.ui.control.Label
        TrainOnListBox                  matlab.ui.control.ListBox
        TrainOnListBoxLabel             matlab.ui.control.Label
        VisualizationThresholdsPanel    matlab.ui.container.Panel
        ExportModelmatButton            matlab.ui.control.Button
        DataValuesLabel                 matlab.ui.control.Label
        MultTestStrategy                matlab.ui.control.DropDown
        levelEditField                  matlab.ui.control.NumericEditField
        levelEditFieldLabel             matlab.ui.control.Label
        AutoRefreshCheckBox             matlab.ui.control.CheckBox
        RefreshViewButton               matlab.ui.control.Button
        ConsiderSignificantVoxelsonlyCheckBox  matlab.ui.control.CheckBox
        ColorButtonPositivePeak         matlab.ui.control.Button
        ColorButtonNegativePeak         matlab.ui.control.Button
        ExportasNIfTIButton             matlab.ui.control.Button
        SplitColorbyGroupVariableCheckBox  matlab.ui.control.CheckBox
        ShowNegativeVoxelsCheckBox      matlab.ui.control.CheckBox
        ShowPositiveVoxelsCheckBox      matlab.ui.control.CheckBox
        ColorButtonNegativeBase         matlab.ui.control.Button
        ColorButtonPositiveBase         matlab.ui.control.Button
        ColorBar                        matlab.ui.control.UIAxes
        BusyLamp                        matlab.ui.control.Lamp
        BusyLampLabel                   matlab.ui.control.Label
        InteractiveModelSetupPanel      matlab.ui.container.Panel
        NormalizeZeroCenterVOIDropDown  matlab.ui.control.DropDown
        NormalizeZeroCenterVOIDropDownLabel  matlab.ui.control.Label
        ImprovementThresholdEditField   matlab.ui.control.NumericEditField
        ImprovementThresholdEditFieldLabel  matlab.ui.control.Label
        NThresholdinEditField           matlab.ui.control.NumericEditField
        NThresholdinEditFieldLabel      matlab.ui.control.Label
        CorrectionDropDown              matlab.ui.control.DropDown
        CorrectionDropDownLabel         matlab.ui.control.Label
        AgainstDropDown                 matlab.ui.control.DropDown
        AgainstLabel                    matlab.ui.control.Label
        StatisticalTestDropDown         matlab.ui.control.DropDown
        StatisticalTestDropDownLabel    matlab.ui.control.Label
        VariableofInterestVOIDropDown   matlab.ui.control.DropDown
        VariableofInterestVOIDropDownLabel  matlab.ui.control.Label
        CleanVOIfromthefollowingcovariatesCheckBox  matlab.ui.control.CheckBox
        CovariatesListBox               matlab.ui.control.ListBox
        ConnthresholdLabel              matlab.ui.control.Label
        ConnthresholdSlider             matlab.ui.control.Slider
        Corrtype                        matlab.ui.control.DropDown
        VTAthresholdVmmEditField        matlab.ui.control.NumericEditField
        VTAthresholdVmmEditFieldLabel   matlab.ui.control.Label
        InspireAnalysisbyDropDown       matlab.ui.control.DropDown
        InspireAnalysisbyDropDownLabel  matlab.ui.control.Label
        SubcohortsDropDown              matlab.ui.control.DropDown
        SubcohortsDropDownLabel         matlab.ui.control.Label
        AnalysisLevelDropDown           matlab.ui.control.DropDown
        AnalysisLevelDropDownLabel      matlab.ui.control.Label
        PatientsListBox                 matlab.ui.control.ListBox
        PatientsListBoxLabel            matlab.ui.control.Label
        MirrorSidesCheckBox             matlab.ui.control.CheckBox
        Label                           matlab.ui.control.Label
        LeadGroupPathLabel              matlab.ui.control.Label
        AnalysisIDEditField             matlab.ui.control.EditField
        AnalysisIDEditFieldLabel        matlab.ui.control.Label
    end

    
    properties (Access = public)
        sweetspot % associated ea_disctract object.
        PermTestModelFile = '' % path to a training object's exported voxelwise permutation test (_permtest.mat), used by the "Import Voxelwise PermTest (.mat)" strategy
    end
    
    
    methods (Access = private)
        
        function results = drawcb(app)
            app.isbusy(1);
            if ~isempty(app.sweetspot.colorbar)
                if app.sweetspot.splitbygroup
                    app.ColorBar.Visible = 'off';
                    cla(app.ColorBar);
                else
                    app.ColorBar.Visible = 'on';
                    cb = app.sweetspot.colorbar;
                    ea_plot_colorbar(cb.cmap{1}, [], 'h', '', cb.tick{1}, cb.ticklabel{1}, app.ColorBar);
                end
            else
                app.ColorBar.Visible = 'off';
                cla(app.ColorBar);
            end
            app.isbusy(0);
        end
        
        function results = enablerules(app)
            app.isbusy(1);  

            app.SubcohortsDropDown_2.Items=[app.sweetspot.setlabels];
            
            if app.SplitColorbyGroupVariableCheckBox.Value
                app.ColorBar.Visible = 'off';
                cla(app.ColorBar);
                app.ExportasNIfTIButton.Enable = 'off';
                cvEnable = 'off';
            else
                app.ColorBar.Visible = 'on';
                
                app.ExportasNIfTIButton.Enable = 'on';
                cvEnable = 'on';
            end
            
            
            try
                numsides=numel(app.tractset.results.efield);
            catch % default
                numsides=2;
            end
            
            setcovars(app);
            
            % CV Panel:
            app.StrategyDropDown.Enable = cvEnable;
            app.StrategyDropDownLabel.Enable = cvEnable;
            app.kEditField.Enable = cvEnable;
            app.kEditFieldLabel.Enable = cvEnable;
            app.RunCVButton.Enable = cvEnable;
            app.PosthoccorrectforgroupCheckBox.Enable = cvEnable;
            updatethreshtext(app);
            switch app.AnalysisLevelDropDown.Value
                case 'VTAs'
                    app.VTAthresholdVmmEditField.Enable = 'on';
                    app.VTAthresholdVmmEditFieldLabel.Enable = 'on';
                    app.VTAthresholdVmmEditFieldLabel.Text = 'VTA threshold [V/mm]';
                    app.StatisticalTestDropDown.Items={'Mean-Image','N-Image','T-Test','Wilcoxon-Test','Proportion Test (Binary Var)','Binomial Test (Binary Var)'};
                    app.CorrectionDropDown.Enable = 'on'; 
                    app.CorrectionDropDownLabel.Enable = 'on'; 
                    app.BasePredictiononDropDown.Items={'Mean of Scores','Sum of Scores','Peak of Scores','Peak 5% of Scores'};
                    app.Corrtype.Enable = 'off';
                    app.InspireAnalysisbyDropDown.Items = {'Butson 2011', 'Cheung 2011', 'Eisenstein 2014', 'Reich 2019', 'Dembek 2019', 'Elias 2020', 'Custom'};
                case 'E-Fields'
                    app.VTAthresholdVmmEditField.Enable = 'on';
                    app.VTAthresholdVmmEditFieldLabel.Enable = 'on';
                    app.VTAthresholdVmmEditFieldLabel.Text = 'E-Field threshold [V/mm]';
                    app.StatisticalTestDropDown.Items={'Correlations','Reverse T-Tests (Binary Var)'};
                    app.CorrectionDropDown.Enable = 'off'; 
                    app.CorrectionDropDownLabel.Enable = 'off'; 
                    app.BasePredictiononDropDown.Items={'Mean of Scores','Sum of Scores','Peak of Scores','Peak 5% of Scores','Profile of Scores: Spearman','Profile of Scores: Pearson','Profile of Scores: Bend'};
                    app.Corrtype.Enable = 'on';
                    app.InspireAnalysisbyDropDown.Items={'Custom'};
                case 'Active Coordinates'
                    app.VTAthresholdVmmEditField.Enable = 'off';
                    app.VTAthresholdVmmEditFieldLabel.Enable = 'off';
                    app.Corrtype.Enable = 'off';
                    app.BasePredictiononDropDown.Items={'Distance to Optimal Coordinate'};
                    app.InspireAnalysisbyDropDown.Items={'Distance to Weighted Mean'};
            end
            %             app.sweetspot.statconcept=app.InspireAnalysisbyDropDown.Value;
            if ~isempty(app.sweetspot.responsevar)
                binaryvarselected=ea_isbinary(app.sweetspot.responsevar(app.sweetspot.patientselection));
                impthreshenable=ea_bool2onoff(~binaryvarselected);
            else
                impthreshenable='on';
            end
            switch app.StatisticalTestDropDown.Value
                case 'Mean-Image' % VTA level
                    app.AgainstDropDown.Enable = 'off';
                    app.AgainstLabel.Enable = 'off';
                    app.ImprovementThresholdEditField.Enable = impthreshenable;
                    app.ImprovementThresholdEditFieldLabel.Enable = impthreshenable;
                    app.NThresholdinEditField.Enable = 'off';
                    app.NThresholdinEditFieldLabel.Enable = 'off';
                    app.ConsiderSignificantVoxelsonlyCheckBox.Enable = 'off';
                    app.ConsiderSignificantVoxelsonlyCheckBox.Value = 0;
                    app.levelEditField.Enable = 'off';
                    app.MultTestStrategy.Enable = 'off';
                    switch app.CorrectionDropDown.Value
                        case 'Amplitude'
                            app.DataValuesLabel.Text = ['Average ',app.VariableofInterestVOIDropDown.Value,'/amp'];
                        case 'VTA Size'
                            app.DataValuesLabel.Text = ['Average ',app.VariableofInterestVOIDropDown.Value,'/vx'];
                        otherwise
                            app.DataValuesLabel.Text = ['Average ',app.VariableofInterestVOIDropDown.Value];
                    end
                case 'N-Image' % VTA level
                    app.AgainstDropDown.Enable = 'off';
                    app.AgainstLabel.Enable = 'off';
                    app.ImprovementThresholdEditField.Enable = impthreshenable;
                    app.ImprovementThresholdEditFieldLabel.Enable = impthreshenable;
                    app.NThresholdinEditField.Enable = 'on';
                    app.NThresholdinEditFieldLabel.Enable = 'on';
                    app.ConsiderSignificantVoxelsonlyCheckBox.Enable = 'off';
                    app.ConsiderSignificantVoxelsonlyCheckBox.Value = 0;
                    app.levelEditField.Enable = 'off';
                    app.MultTestStrategy.Enable = 'off';
                    app.DataValuesLabel.Text = 'Voxel covered by N VTAs';
                case 'Correlations' % E-Field level
                    app.AgainstDropDown.Enable = 'off';
                    app.AgainstLabel.Enable = 'off';
                    app.ImprovementThresholdEditField.Enable = 'off';
                    app.ImprovementThresholdEditFieldLabel.Enable = 'off';
                    app.NThresholdinEditField.Enable = 'off';
                    app.NThresholdinEditFieldLabel.Enable = 'off';
                    app.ConsiderSignificantVoxelsonlyCheckBox.Enable = 'on';
                    app.levelEditField.Enable = 'on';
                    app.MultTestStrategy.Enable = 'on';
                    app.DataValuesLabel.Text = ['R-Values (',app.Corrtype.Value,')'];
                case 'Reverse T-Tests (Binary Var)' % E-Field level
                    app.AgainstDropDown.Enable = 'off';
                    app.AgainstLabel.Enable = 'off';
                    app.ImprovementThresholdEditField.Enable = 'off';
                    app.ImprovementThresholdEditFieldLabel.Enable = 'off';
                    app.NThresholdinEditField.Enable = 'off';
                    app.NThresholdinEditFieldLabel.Enable = 'off';
                    app.ConsiderSignificantVoxelsonlyCheckBox.Enable = 'on';
                    app.levelEditField.Enable = 'on';
                    app.MultTestStrategy.Enable = 'on';
                    app.DataValuesLabel.Text = ['T-Values (',app.Corrtype.Value,')'];
                case 'T-Test'
                    app.AgainstDropDown.Enable = 'on';
                    app.AgainstLabel.Enable = 'on';
                    switch app.AgainstDropDown.Value
                        case 'Threshold'
                            app.ImprovementThresholdEditField.Enable = impthreshenable;
                            app.ImprovementThresholdEditFieldLabel.Enable = impthreshenable;
                        otherwise
                            app.ImprovementThresholdEditField.Enable = 'off';
                            app.ImprovementThresholdEditFieldLabel.Enable = 'off';
                    end
                    app.NThresholdinEditField.Enable = 'off';
                    app.NThresholdinEditFieldLabel.Enable = 'off';
                    app.ConsiderSignificantVoxelsonlyCheckBox.Enable = 'on';
                    app.levelEditField.Enable = 'on';
                    app.MultTestStrategy.Enable = 'on';
                    app.DataValuesLabel.Text = 'T-Values';
                case 'Wilcoxon-Test'
                    app.AgainstDropDown.Enable = 'on';
                    app.AgainstLabel.Enable = 'on';
                    switch app.AgainstDropDown.Value
                        case 'Threshold'
                            app.ImprovementThresholdEditField.Enable = impthreshenable;
                            app.ImprovementThresholdEditFieldLabel.Enable = impthreshenable;
                        otherwise
                            app.ImprovementThresholdEditField.Enable = 'off';
                            app.ImprovementThresholdEditFieldLabel.Enable = 'off';
                    end
                    app.NThresholdinEditField.Enable = 'off';
                    app.NThresholdinEditFieldLabel.Enable = 'off';
                    app.ConsiderSignificantVoxelsonlyCheckBox.Enable = 'on';
                    app.levelEditField.Enable = 'on';
                    app.MultTestStrategy.Enable = 'on';
                    app.DataValuesLabel.Text = '-log10 (p-Values)';
                case 'Proportion Test (Binary Var)'
                    app.AgainstDropDown.Enable = 'off';
                    app.AgainstLabel.Enable = 'off';
                    app.ImprovementThresholdEditField.Enable = 'off';
                    app.ImprovementThresholdEditFieldLabel.Enable = 'off';
                    app.NThresholdinEditField.Enable = 'off';
                    app.NThresholdinEditFieldLabel.Enable = 'off';
                    app.ConsiderSignificantVoxelsonlyCheckBox.Enable = 'on';
                    app.levelEditField.Enable = 'on';
                    app.MultTestStrategy.Enable = 'on';
                    app.DataValuesLabel.Text = ['z-values'];      
                case 'Binomial Test (Binary Var)'
                    app.AgainstDropDown.Enable = 'off';
                    app.AgainstLabel.Enable = 'off';
                    app.ImprovementThresholdEditField.Enable = 'off';
                    app.ImprovementThresholdEditFieldLabel.Enable = 'off';
                    app.NThresholdinEditField.Enable = 'off';
                    app.NThresholdinEditFieldLabel.Enable = 'off';
                    app.ConsiderSignificantVoxelsonlyCheckBox.Enable = 'on';
                    app.levelEditField.Enable = 'on';
                    app.MultTestStrategy.Enable = 'on';
                    app.DataValuesLabel.Text = ['pdf-values']; 
            end
            app.sweetspot.statimpthreshold = app.ImprovementThresholdEditField.Value;
            app.sweetspot.statNthreshold = app.NThresholdinEditField.Value;
            
            switch app.StatisticalTestDropDown.Value % show neg enable depending on test used
                case {'N-Image'}
                    app.ShowNegativeVoxelsCheckBox.Enable = 'off';
                    app.ShowNegativeVoxelsCheckBox.Value = 0;
                otherwise
                    app.ShowNegativeVoxelsCheckBox.Enable = 'on';
            end
            app.sweetspot.showsignificantonly = app.ConsiderSignificantVoxelsonlyCheckBox.Value;
            app.sweetspot.negvisible = app.ShowNegativeVoxelsCheckBox.Value;
            
            switch app.StrategyDropDown.Value
                case 'k-fold (randomized)'
                    app.kEditField.Visible = 'on';
                    app.kEditFieldLabel.Visible = 'on';
                    app.kEditFieldLabel.Text = 'k:';
                    app.kEditField.Tooltip = '';
                    app.kEditField.Value = num2str(app.sweetspot.kfold);
                    app.ShowImportedModelCheckBox.Visible = 'off';
                    app.ImportedModelShow.Visible = 'off';
                    app.LoadModelButton.Visible = 'off';
                    app.sweetspot.useExternalModel = false;
                    app.SubcohortsDropDown_2.Visible = 'off';
                    app.SubcohortsDropDown_2Label.Visible = 'off';
                    app.TrainOnListBox.Visible = 'on';
                case 'Leave-Nothing-Out (Permutation-Based)'
                    app.kEditField.Visible = 'on';
                    app.kEditFieldLabel.Visible = 'on';
                    app.kEditFieldLabel.Text = '# perms:';
                    app.kEditField.Tooltip = '';
                    app.kEditField.Value = num2str(app.sweetspot.Nperm);
                    app.ShowImportedModelCheckBox.Visible = 'off';
                    app.ImportedModelShow.Visible = 'off';
                    app.LoadModelButton.Visible = 'off';
                    app.sweetspot.useExternalModel = false;
                    app.SubcohortsDropDown_2.Visible = 'off';
                    app.SubcohortsDropDown_2Label.Visible = 'off';
                    app.TrainOnListBox.Visible = 'on';
                case 'Voxelwise Permutation Test'
                    app.kEditField.Visible = 'on';
                    app.kEditFieldLabel.Visible = 'on';
                    app.kEditFieldLabel.Text = '# perms:';
                    app.kEditField.Tooltip = '';
                    app.kEditField.Value = num2str(app.sweetspot.Nperm);
                    app.ShowImportedModelCheckBox.Visible = 'off';
                    app.ImportedModelShow.Visible = 'off';
                    app.LoadModelButton.Visible = 'off';
                    app.sweetspot.useExternalModel = false;
                    app.SubcohortsDropDown_2.Visible = 'off';
                    app.SubcohortsDropDown_2Label.Visible = 'off';
                    app.TrainOnListBox.Visible = 'off';
                case 'Custom (Sets)'
                    app.kEditField.Visible = 'on';
                    app.kEditFieldLabel.Visible = 'on';
                    app.kEditFieldLabel.Text = '# sets:';
                    app.kEditField.Tooltip = 'Randomly split the data into N sets';
                    app.kEditField.Value = num2str(app.sweetspot.Nsets);
                    app.ShowImportedModelCheckBox.Visible = 'off';
                    app.ImportedModelShow.Visible = 'off';
                    app.LoadModelButton.Visible = 'off';
                    app.sweetspot.useExternalModel = false;
                    app.SubcohortsDropDown_2.Visible = 'off';
                    app.SubcohortsDropDown_2Label.Visible = 'off';
                    app.TrainOnListBox.Visible = 'on';
                case 'Import Model (.mat)'   
                    app.LoadModelButton.Visible = 'on';
                    app.LoadPermTestModelButton.Visible = 'off';
                    app.sweetspot.useExternalModel = true;
                    app.SubcohortsDropDown_2.Visible = 'on';
                    app.SubcohortsDropDown_2Label.Visible = 'on';
                    app.TrainOnListBox.Visible = 'off';
                    app.ImportedModelShow.Visible = 'on';
                    app.kEditFieldLabel.Visible = 'off';
                    app.ShowImportedModelCheckBox.Visible = 'on';
                    app.LiveVisualizeCheckBox.Visible = 'off';
                case 'Import Voxelwise PermTest (.mat)'
                    app.LoadModelButton.Visible = 'off';
                    app.LoadPermTestModelButton.Visible = 'on';
                    app.sweetspot.useExternalModel = false;
                    app.SubcohortsDropDown_2.Visible = 'on';
                    app.SubcohortsDropDown_2Label.Visible = 'on';
                    app.TrainOnListBox.Visible = 'off';
                    app.ImportedModelShow.Visible = 'on';
                    app.kEditField.Visible = 'off';
                    app.kEditFieldLabel.Visible = 'off';
                    app.ShowImportedModelCheckBox.Visible = 'off';
                    app.LiveVisualizeCheckBox.Visible = 'off';
                otherwise
                    app.kEditField.Visible = 'off';
                    app.kEditFieldLabel.Visible = 'off';
                    app.kEditField.Tooltip = '';
                    app.LoadModelButton.Visible = 'off';
                    app.LoadPermTestModelButton.Visible = 'off';
                    app.sweetspot.useExternalModel = false;
                    app.SubcohortsDropDown_2.Visible = 'off';
                    app.SubcohortsDropDown_2Label.Visible = 'off';
                    app.TrainOnListBox.Visible = 'on';
                    app.ImportedModelShow.Visible = 'off';
                    app.LiveVisualizeCheckBox.Visible = 'on';
                    app.ShowImportedModelCheckBox.Visible = 'off';
            end

            if strcmp(app.StrategyDropDown.Value, 'Voxelwise Permutation Test')
                app.BasePredictiononDropDown.Visible = 'off';
                app.BasePredictiononDropDownLabel.Visible = 'off';
                app.PosthoccorrectforgroupCheckBox.Visible = 'off';
                app.LiveVisualizeCheckBox.Visible = 'off';
                app.PredictListBox.Visible = 'off';
                app.PredictListBoxLabel.Visible = 'off';
                app.TrainOnListBoxLabel.Visible = 'off';
                app.PermuteWithinGroupsCheckBox.Visible = 'on';
                app.PermuteWithinGroupsCheckBox.Value = app.sweetspot.stratifyPermutationsByGroup;
            else
                app.BasePredictiononDropDown.Visible = 'on';
                app.BasePredictiononDropDownLabel.Visible = 'on';
                app.PosthoccorrectforgroupCheckBox.Visible = 'on';
                app.PredictListBox.Visible = 'on';
                app.PredictListBoxLabel.Visible = 'on';
                app.TrainOnListBoxLabel.Visible = 'on';
                app.PermuteWithinGroupsCheckBox.Visible = 'off';
            end

            if strcmp(app.StrategyDropDown.Value, 'Import Voxelwise PermTest (.mat)')
                app.SigModeDropDown.Visible = 'on';
                app.SigModeDropDownLabel.Visible = 'on';
            else
                app.SigModeDropDown.Visible = 'off';
                app.SigModeDropDownLabel.Visible = 'off';
            end
            
            switch app.StrategyDropDown.Value
                case 'Custom (Patients)'
                    app.TrainOnListBox.Items=app.sweetspot.M.patient.list;
                    app.PredictListBox.Items=app.sweetspot.M.patient.list;
                case 'Custom (Cohorts)'
                    if length(app.sweetspot.M.groups.group)>1
                        app.TrainOnListBox.Items=sprintfc('%d',(app.sweetspot.M.groups.group));
                        app.PredictListBox.Items=sprintfc('%d',(app.sweetspot.M.groups.group));
                    end
                case 'Custom (Sets)'
                    if str2double(app.kEditField.Value)>1
                        app.TrainOnListBox.Items=sprintfc('%d',[1:str2double(app.kEditField.Value)]');
                        app.PredictListBox.Items=sprintfc('%d',[1:str2double(app.kEditField.Value)]');
                    end
                case 'Custom (Subcohorts)'
                    app.TrainOnListBox.Items=app.sweetspot.setlabels;
                    app.PredictListBox.Items=app.sweetspot.setlabels;
                case 'Import Model (.mat)'
                    app.PredictListBox.Items = app.sweetspot.M.patient.list; 
                case 'Import Voxelwise PermTest (.mat)'
                    app.PredictListBox.Items = app.sweetspot.M.patient.list;
            end
            
            switch app.StrategyDropDown.Value
                case 'Custom (Patients)'
                    cvlEnable=cvEnable;
                    if isempty(app.TrainOnListBox.Value) || isempty(app.PredictListBox.Value)
                        app.RunCVButton.Enable = 'off';
                    end
                case 'Custom (Cohorts)'
                    if length(app.sweetspot.M.groups.group)>1
                        cvlEnable=cvEnable;
                        if isempty(app.TrainOnListBox.Value) || isempty(app.PredictListBox.Value)
                            app.RunCVButton.Enable = 'off';
                        end
                    else
                        app.RunCVButton.Enable = 'off';
                        cvlEnable='off';
                    end
                    
                case 'Custom (Sets)'
                    if str2double(app.kEditField.Value)>1
                        if isempty(app.TrainOnListBox.Value) || isempty(app.PredictListBox.Value)
                            app.RunCVButton.Enable = 'off';
                        end
                        cvlEnable=cvEnable;
                    else
                        app.RunCVButton.Enable = 'off';
                        cvlEnable='off';
                    end
                case 'Custom (Subcohorts)'
                    if isempty(app.TrainOnListBox.Value) || isempty(app.PredictListBox.Value)
                        app.RunCVButton.Enable = 'off';
                    end
                    cvlEnable='on';
                case 'Import Model (.mat)'
                    cvlEnable=cvEnable;
                    if isempty(app.ImportedModelShow.Value) || isempty(app.PredictListBox.Value)
                        app.RunCVButton.Enable = 'off';
                    end
                case 'Import Voxelwise PermTest (.mat)'
                    cvlEnable=cvEnable;
                    if isempty(app.PermTestModelFile) || isempty(app.PredictListBox.Value)
                        app.RunCVButton.Enable = 'off';
                    end
                otherwise
                    cvlEnable='off';
            end
            
            % CV Listboxes
            app.PredictListBox.Enable = cvlEnable;
            app.PredictListBoxLabel.Enable = cvlEnable;
            app.TrainOnListBox.Enable = cvlEnable;
            app.TrainOnListBoxLabel.Enable = cvlEnable;
            
            app.SubcohortsDropDown.Items=[{'Choose...', 'All Patients', 'Create Subcohort from Selection', 'Create Subcohort from Inverse Selection'},app.sweetspot.setlabels];
            

            if isfield(app.sweetspot.M,'pseudoM')
                if app.sweetspot.M.pseudoM
                    %                     app.AnalysisLevelDropDownLabel.Visible='off';
                    %                     app.AnalysisIDEditFieldLabel.Visible='off';
                    %                     app.VTAthresholdVmmEditFieldLabel.Visible='off';
                    %                     app.AnalysisLevelDropDown.Value='VTAs';
                    %                     app.AnalysisLevelDropDown.Enable='off';
                    %                     app.VTAthresholdVmmEditField.Enable='off';
                    %                     app.AnalysisLevelDropDown.Visible='off';
                    %                     app.VTAthresholdVmmEditField.Visible='off';
                    %                     app.sweetspot.efieldthreshold=0;
                end
            end
            
            
            % turn light back to green
            app.isbusy(0);
        end
        
        
        function results = sweetspot2UI(app)
            app.isbusy(1);
            
            app.AnalysisLevelDropDown.Enable = 'on';
            app.AnalysisLevelDropDownLabel.Enable = 'on';
            
            
            % derive everything from tractset obj:
            app.LeadGroupPathLabel.Text=app.sweetspot.leadgroup;
            app.LeadGroupPathLabel.Tooltip=app.sweetspot.leadgroup;
            app.AnalysisIDEditField.Value = app.sweetspot.ID;
            app.AnalysisLevelDropDown.Value = app.sweetspot.statlevel;
            %             app.InspireAnalysisbyDropDown.Value = app.sweetspot.statconcept;
            
            switch app.sweetspot.statlevel
                case 'VTAs'
                    if isfield(app.sweetspot.M,'pseudoM') % custom scripting input
                        app.CovariatesListBox.Items=['Group / Cohort regressor',app.sweetspot.M.clinical.labels];
                    else % regular case
                        app.CovariatesListBox.Items=['Group / Cohort regressor','Stimulation Amplitude','VTA Volume',app.sweetspot.M.clinical.labels];
                    end
                case 'E-Fields'
                    if isfield(app.sweetspot.M,'pseudoM') % custom scripting input
                        app.CovariatesListBox.Items=['Group / Cohort regressor','Stimulation Amplitude','E-Field Magnitude Sum',app.sweetspot.M.clinical.labels];
                    else % regular case
                        app.CovariatesListBox.Items=['Group / Cohort regressor',app.sweetspot.M.clinical.labels];
                    end
                case 'Active Contacts'
                    app.CovariatesListBox.Items=['Group / Cohort regressor',app.sweetspot.M.clinical.labels];
            end
            
            app.Corrtype.Value=app.sweetspot.corrtype;
            %             app.InspireAnalysisbyDropDown.Value=app.sweetspot.statconcept;
            app.ConsiderSignificantVoxelsonlyCheckBox.Value = app.sweetspot.showsignificantonly;
            app.levelEditField.Value = (app.sweetspot.alphalevel);
            app.MultTestStrategy.Value = app.sweetspot.multcompstrategy;
            try % could be that entry is not in the items list.
                app.BasePredictiononDropDown.Value = app.sweetspot.basepredictionon;
            end
            
            app.MirrorSidesCheckBox.Value = app.sweetspot.mirrorsides;
            app.ShowPositiveVoxelsCheckBox.Value = app.sweetspot.posvisible;
            app.ShowNegativeVoxelsCheckBox.Value = app.sweetspot.negvisible;
            
            app.PatientsListBox.Items=app.sweetspot.M.patient.list;
            app.PatientsListBox.Value=app.PatientsListBox.Items(app.sweetspot.patientselection);
            app.SubcohortsDropDown.Value='Choose...';
            for set=1:length(app.sweetspot.setlabels)
                if isequal(app.sweetspot.patientselection,find(app.sweetspot.setselections{set}))
                    app.SubcohortsDropDown.Value=app.sweetspot.setlabels{set};
                end
            end
            if isequal(app.sweetspot.patientselection,1:length(app.sweetspot.M.patient.list))
                app.SubcohortsDropDown.Value='All Patients';
            end
            
            
            if ~isempty(app.sweetspot.efieldthreshold)
                app.VTAthresholdVmmEditField.Value = app.sweetspot.efieldthreshold/1000;
            end
            
            
            app.PosthoccorrectforgroupCheckBox.Value = app.sweetspot.adjustforgroups;
            
            app.VariableofInterestVOIDropDown.Items=app.sweetspot.M.clinical.labels;
            app.VariableofInterestVOIDropDown.Value = app.sweetspot.responsevarlabel;
            
            app.NormalizeZeroCenterVOIDropDown.Value = app.sweetspot.statnormalization;

            [~,ix]=ismember(app.VariableofInterestVOIDropDown.Value,app.CovariatesListBox.Items);
            app.CovariatesListBox.Items(ix)=[];
            
            app.CovariatesListBox.Value=app.sweetspot.covarlabels;
            
            if ~isempty(app.sweetspot.results)
                app.CleanVOIfromthefollowingcovariatesCheckBox.Value=~isempty(app.sweetspot.covarlabels);
            end
            
            if ~isempty(app.sweetspot.splitbygroup)
                app.SplitColorbyGroupVariableCheckBox.Value = app.sweetspot.splitbygroup;
            end
            
            try
                app.StatisticalTestDropDown.Value = app.sweetspot.stattest;
            catch
                enablerules(app);
                app.StatisticalTestDropDown.Value = app.sweetspot.stattest;
            end
            app.AgainstDropDown.Value = app.sweetspot.stat0hypothesis;
            app.ImprovementThresholdEditField.Value = app.sweetspot.statimpthreshold;
            app.NThresholdinEditField.Value = app.sweetspot.statNthreshold;
            app.CorrectionDropDown.Value = app.sweetspot.statamplitudecorrection;


            app.AutoRefreshCheckBox.Value = app.sweetspot.autorefresh;
            
            app.LiveVisualizeCheckBox.Value = app.sweetspot.cvlivevisualize;
            
            % Color buttons:
            app.ColorButtonPositiveBase.BackgroundColor = app.sweetspot.posBaseColor;
            app.ColorButtonPositivePeak.BackgroundColor = app.sweetspot.posPeakColor;
            app.ColorButtonNegativeBase.BackgroundColor = app.sweetspot.negBaseColor;
            app.ColorButtonNegativePeak.BackgroundColor = app.sweetspot.negPeakColor;
            
            
            % refresh enablerules
            app.enablerules;
            
            app.isbusy(0);
        end
        
        function results = isbusy(app,business,msg)
            if business == 1 % red
                app.BusyLamp.Color = [1.00,0.41,0.16];
                if exist('msg','var')
                    app.BusyLampLabel.Text = msg;
                else
                    app.BusyLampLabel.Text = 'Busy';
                end
            elseif business == 0 % green
                app.BusyLamp.Color = [0.41,1.00,0.16];
                app.BusyLampLabel.Text = '';
            elseif business == 2 % yellow
                app.BusyLamp.Color = [1.00,0.8,0.16];
                if exist('msg','var')
                    app.BusyLampLabel.Text = msg;
                else
                    app.BusyLampLabel.Text = 'Not Refreshed';
                end
            end
        end
        
        function results = draw(app,force)
            if ~exist('force','var')
                force=0;
            end
            app.RefreshViewButton.BackgroundColor = [0.96,0.96,0.96]; % standard grey
            if app.AutoRefreshCheckBox.Value || force
                app.isbusy(1);
                drawnow;
                
                app.sweetspot.draw;
                app.drawcb;
                app.isbusy(0);
                drawnow;
                app.sweetspot2UI;
            else
                app.isbusy(2);
                app.RefreshViewButton.BackgroundColor = [1,0.8,0.16]; % yellow
                drawnow;
            end
        end
        
        function Vms = getvms(app)
            switch app.sweetspot.efieldmetric
                case 'Mean'
                    Vms='V/mm';
                otherwise
                    Vms='V/m';
            end
        end
        
        function ts = setcovars(app) % transfer covariates from app to tractset
            if app.CleanVOIfromthefollowingcovariatesCheckBox.Value % include covariates at all
                app.CovariatesListBox.Enable = 'on';
                
                [is]=ismember(app.sweetspot.M.clinical.labels,app.CovariatesListBox.Value);
                if any(is)
                    app.sweetspot.covars = app.sweetspot.M.clinical.vars(is);
                    app.sweetspot.covarlabels = app.sweetspot.M.clinical.labels(is);
                else % none selected
                    app.sweetspot.covars = {};
                    app.sweetspot.covarlabels = {};
                end
                % Check whether Amplitude or VTA volumes should be
                % included, as well.
                if ismember('Stimulation Amplitude',app.CovariatesListBox.Value)
                    app.sweetspot.covars = [{app.sweetspot.getstimamp},app.sweetspot.covars];
                    app.sweetspot.covarlabels = [{'Stimulation Amplitude'},app.sweetspot.covarlabels];
                end
                
                if ismember('VTA Volume',app.CovariatesListBox.Value)
                    app.sweetspot.covars = [{app.sweetspot.getvtavolumes},app.sweetspot.covars];
                    app.sweetspot.covarlabels = [{'VTA Volume'},app.sweetspot.covarlabels];
                end
                
                if ismember('E-Field Magnitude Sum',app.CovariatesListBox.Value)
                    app.sweetspot.covars = [{app.sweetspot.getefieldmagnitudes},app.sweetspot.covars];
                    app.sweetspot.covarlabels = [{'E-Field Magnitude Sum'},app.sweetspot.covarlabels];
                end
                if ismember('Group / Cohort regressor',app.CovariatesListBox.Value)
                    app.sweetspot.covars = [{app.sweetspot.getcohortregressor},app.sweetspot.covars];
                    app.sweetspot.covarlabels = [{'Group / Cohort regressor'},app.sweetspot.covarlabels];
                end
            else
                app.CovariatesListBox.Enable = 'off';
                app.sweetspot.covars = {};
                app.sweetspot.covarlabels = {};
            end
        end
        
        function results = updatethreshtext(app)
            app.ConnthresholdSlider.Value = app.sweetspot.coverthreshold;
            switch app.AnalysisLevelDropDown.Value
                case 'VTAs'
                    app.ConnthresholdLabel.Text = ['Voxels should be covered by at least ',sprintf('%.0f',app.sweetspot.coverthreshold*...
                        length(app.sweetspot.patientselection)/100),' (',sprintf('%.1f',app.sweetspot.coverthreshold),'%) of VTAs'];
                case 'E-Fields'
                    app.ConnthresholdLabel.Text = ['Voxels should be reached by at least ',sprintf('%.0f',app.sweetspot.coverthreshold*...
                        length(app.sweetspot.patientselection)/100),' (',sprintf('%.1f',app.sweetspot.coverthreshold),'%) of thresholded E-Fields'];
            end
            if isfield(app.sweetspot.M,'pseudoM')
                app.ConnthresholdLabel.Text = ['Voxels should be reached by at least ',sprintf('%.0f',app.sweetspot.coverthreshold*...
                    length(app.sweetspot.patientselection)/100),' (',sprintf('%.1f',app.sweetspot.coverthreshold),'%) of ROI'];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, lgpath, resultfig)
            app.isbusy(1,'Loading');
            app.UIFigure.Name='Sweetspot Explorer';

            % use supplied information to setup analysis
            if ~exist('lgpath','var')
                [FileName,PathName]=uigetfile({'*.mat;*.sweetspot','Group or Sweetspot Files'},'Please Choose Lead-Group Analysis project...');
                lgpath = fullfile(PathName,FileName);
            elseif ~isfile(lgpath)
                ea_cprintf('CmdWinErrors', 'Analysis file doesn''t exist!\n');
                delete(app);
                return;
            end
            lgpath = GetFullPath(lgpath);

            if ~exist('resultfig','var')
                resultfig = ea_mnifigure;
            end

            [~,~,ext]=fileparts(lgpath); % check if file is a group analysis mat file or a stored disctract object with var name 'tractset'
            if strcmp('.sweetspot',ext)
                D = load(lgpath,'-mat');
                app.sweetspot = D.sweetspot; % load stored disctract object
                
                
                % Fix sweetspot ID in case it has been renamed
                [~, ID] = fileparts(lgpath);
                if ~strcmp(app.sweetspot.ID, ID)
                    app.sweetspot.ID = ID;
                end
                
                % Fix group folder in case it has been moved
                tractGroupFolder = regexprep(app.sweetspot.leadgroup, ['(?<=\',filesep,')[\w-]+\.mat$'], '');
                UIGroupFolder = strrep(lgpath,['sweetspots', filesep, app.sweetspot.ID, '.sweetspot'],'');
                if ~strcmp(tractGroupFolder, UIGroupFolder)
                    app.sweetspot.analysispath = strrep(app.sweetspot.analysispath, tractGroupFolder, UIGroupFolder);
                    app.sweetspot.leadgroup = strrep(app.sweetspot.leadgroup, tractGroupFolder, UIGroupFolder);
                    app.sweetspot.M.root = strrep(app.sweetspot.M.root, tractGroupFolder, UIGroupFolder);
                end
                
                app.sweetspot.resultfig = resultfig; % attach to figure
                
            else % init from M struct of LEAD group file
                app.sweetspot = ea_sweetspot;
                try
                    app.sweetspot.initialize(lgpath,resultfig); % initialize which could lead to drawing in case of loaded obj.
                catch ME
                    close(resultfig);
                    app.UIFigureCloseRequest;
                    rethrow(ME);
                end
            end

            enablerules(app); % calling enablerules twice (here and below) at startup makes sense.
            sweetspot2UI(app);

            app.CovariatesListBox.Items=['Stimulation Amplitude','VTA Volume',app.sweetspot.M.clinical.labels];
            [~,ix]=ismember(app.VariableofInterestVOIDropDown.Value,app.CovariatesListBox.Items);
            app.CovariatesListBox.Items(ix)=[];
            
            [~, ix]=ismember(app.VariableofInterestVOIDropDown.Value,app.sweetspot.M.clinical.labels);
            app.sweetspot.responsevar = app.sweetspot.M.clinical.vars{ix};
            app.sweetspot.responsevarlabel = app.sweetspot.M.clinical.labels{ix};
            
            setcovars(app);

            enablerules(app); % calling enablerules twice (here and above) at startup makes sense.
            app.draw; % show its results
            
            app.isbusy(0);
            hint={'Welcome to the Lead-DBS Sweetspot Explorer!'
                'We are excited about your interest in this tool.'
                ''
                'While the explorer can be a powerful tool, the intended use-case is to'
                'create nsweet-spot models on *part* (e.g. 70%) of your data only.'
                'You can do so by selecting only a subset of patients in the lower right.'
                'Then, the aim is to cross-validate'
                'results using boot-strapping methods (see lower panel).'
                ''
                'Once you are convinced that the model you created is robust and'
                'yields meaningful predictions within the training & cross-validation set,'
                '(e.g. using leave-one-out or k-fold CV routines in the lower left)'
                'it is intended to finally test the model on the remaining (e.g. 30%)'
                'test set of your data (e.g. using the Custom Strategies in the lower left).'
                ''
                'If unsure, please consult the manual or use our Slack help-channel.'
                'Furthermore, we would like to encourage everyone to explore/adapt'
                'their own code & analyses tailored to the studies specific needs.'
                ''
                'In case of code changes that could be useful for others, as well,'
                'consider contributing them via a pull-request on github.'};
            
            uigetpref('LeadDBS','SweetspotShowUsageHint','Sweetspot mapping explorer',hint,'Makes Sense');
            
        end

        % Value changed function: SplitColorbyGroupVariableCheckBox
        function SplitColorbyGroupVariableCheckBoxValueChanged(app, event)
            value = app.SplitColorbyGroupVariableCheckBox.Value;
            app.sweetspot.splitbygroup = value;
            enablerules(app);
            app.draw;
            
        end

        % Value changed function: AnalysisLevelDropDown
        function AnalysisLevelDropDownValueChanged(app, event)
            
            app.sweetspot.statlevel = app.AnalysisLevelDropDown.Value;
            %            sweetspot2UI(app);
            enablerules(app);
            app.sweetspot.stattest = app.StatisticalTestDropDown.Value;
            app.sweetspot.basepredictionon = app.BasePredictiononDropDown.Value;
            app.draw;
            
        end

        % Callback function
        function EfieldthresholdSliderValueChanging(app, event)
            changingValue = event.Value;
            app.EfieldthresholdLabel.Text={['Tracts connected if ',lower(app.sweetspot.efieldmetric),' E-Field Magnitude'] ['they traverse is > ',sprintf('%.2f',changingValue),' ',app.getvms]};
        end

        % Value changed function: VariableofInterestVOIDropDown
        function VariableofInterestVOIDropDownValueChanged(app, event)
            app.CovariatesListBox.Items=['Stimulation Amplitude','VTA Volume',app.sweetspot.M.clinical.labels];
            [~,ix]=ismember(app.VariableofInterestVOIDropDown.Value,app.CovariatesListBox.Items);
            app.CovariatesListBox.Items(ix)=[];
            
            [~, ix]=ismember(app.VariableofInterestVOIDropDown.Value,app.sweetspot.M.clinical.labels);
            app.sweetspot.responsevar = app.sweetspot.M.clinical.vars{ix};
            app.sweetspot.responsevarlabel = app.sweetspot.M.clinical.labels{ix};
            app.draw;
        end

        % Callback function
        function CalculateButtonPushed(app, event)
            %app.sweetspot.app=app; % need to change
            app.sweetspot.leadgroup=app.LeadGroupPathLabel.Text;
            
            %% set up object properties:
            app.sweetspot.posvisible = app.ShowPositiveVoxelsCheckBox.Value;
            app.sweetspot.negvisible = app.ShowNegativeVoxelsCheckBox.Value;
            app.sweetspot.showposamount = [app.RightSliderPositive.Value, app.LeftSliderPositive.Value];
            app.sweetspot.shownegamount = [app.RightSliderNegative.Value, app.LeftSliderNegative.Value];
            app.sweetspot.posBaseColor = app.ColorButtonPositiveBase.BackgroundColor;
            app.sweetspot.negBaseColor = app.ColorButtonNegativeBase.BackgroundColor;
            app.sweetspot.posPeakColor = app.ColorButtonPositivePeak.BackgroundColor;
            app.sweetspot.negPeakColor = app.ColorButtonNegativePeak.BackgroundColor;
            [~, ix]=find(ismember(app.PatientsListBox.Items,app.PatientsListBox.Value));
            app.sweetspot.patientselection = ix;
            
            [~, ix]=ismember(app.VariableofInterestVOIDropDown.Value,app.sweetspot.M.clinical.labels);
            app.sweetspot.responsevar = app.sweetspot.M.clinical.vars{ix};
            app.sweetspot.responsevarlabel = app.sweetspot.M.clinical.labels{ix};
            
            setcovars(app);


            app.sweetspot.connectome = app.ConnectomeDropDown.Value;
            if strcmp(app.Efieldmetric.Value,'Mean')
                app.sweetspot.efieldthreshold = app.EfieldthresholdSlider.Value;
            else
                app.sweetspot.efieldthreshold = app.EfieldthresholdSlider.Value*1000;
            end
            app.sweetspot.connthreshold = app.ConnthresholdSlider.Value;
            app.sweetspot.mirrorsides = app.MirrorSidesCheckBox.Value;
            
            % calculate general setup
            app.sweetspot.calculate;
            
            % save dataset:
            app.sweetspot.save;
            
            enablerules(app);
            
            % draw results
            app.draw;
        end

        % Callback function
        function IncludeallpatientsCheckBoxValueChanged(app, event)
            value = app.IncludeallpatientsCheckBox.Value;
            switch value
                case 1
                    app.PatientsListBox.Value=app.PatientsListBox.Items;
                    app.sweetspot.patientselection = 1:length(app.PatientsListBox.Items);
                case 0
            end
            enablerules(app);
        end

        % Value changed function: ShowPositiveVoxelsCheckBox
        function ShowPositiveVoxelsCheckBoxValueChanged(app, event)
            value = app.ShowPositiveVoxelsCheckBox.Value;
            app.sweetspot.posvisible = value;
            enablerules(app);
            app.draw;
            
        end

        % Value changed function: ShowNegativeVoxelsCheckBox
        function ShowNegativeVoxelsCheckBoxValueChanged(app, event)
            value = app.ShowNegativeVoxelsCheckBox.Value;
            app.sweetspot.negvisible = value;
            enablerules(app);
            app.draw;
            
        end

        % Callback function
        function ShowAmountSliderPositiveValueChanging(app, event)
            changingValue = event.Value;
            app.LeftSliderPositive.Value = changingValue;
            app.RightSliderPositive.Value = changingValue;
            app.sweetspot.showposamount = [app.RightSliderPositive.Value, app.LeftSliderPositive.Value];
        end

        % Callback function
        function ShowAmountSliderNegativeValueChanging(app, event)
            changingValue = event.Value;
            app.LeftSliderNegative.Value = changingValue;
            app.RightSliderNegative.Value = changingValue;
            app.sweetspot.shownegamount = [app.RightSliderNegative.Value, app.LeftSliderNegative.Value];
            
        end

        % Value changing function: AnalysisIDEditField
        function AnalysisIDEditFieldValueChanging(app, event)
            changingValue = event.Value;
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,' ','_');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'(','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,')','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'-','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'.','');
            %
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'"','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'~','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'#','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'^','');
            %
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'|','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'$','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'%','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'&','');
            %
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'*','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'!','');
        end

        % Value changed function: AnalysisIDEditField
        function AnalysisIDEditFieldValueChanged(app, event)
            value = app.AnalysisIDEditField.Value;
            if exist([fileparts(app.sweetspot.leadgroup),filesep,'sweetspots',filesep,value,'.sweetspot'],'file')
                app.AnalysisIDEditField.Value = app.sweetspot.ID;
                msgbox('A tract with this name already exists. Please choose a different name.')
                return
            end
            
            if exist([fileparts(app.sweetspot.leadgroup),filesep,'sweetspots',filesep,app.sweetspot.ID,'.sweetspot'],'file')
                movefile([fileparts(app.sweetspot.leadgroup),filesep,'sweetspots',filesep,app.sweetspot.ID,'.sweetspot'],...
                    [fileparts(app.sweetspot.leadgroup),filesep,'sweetspots',filesep,value,'.sweetspot']);
            end
            
            ht = getappdata(app.sweetspot.resultfig, 'ht');
            if ~isempty(ht)
                Tags = arrayfun(@(tool) tool.Tag, ht.Children, 'Uni', 0);
                pushToolInd = find(contains(Tags,['Explore discriminative fibertract ', app.sweetspot.ID]),1);
                set(ht.Children(pushToolInd), 'Tag', ['Explore discriminative fibertract ', value],...
                    'TooltipString', ['Explore discriminative fibertract ', value], ...
                    'ClickedCallback', {@ea_add_discfiber, [fileparts(app.sweetspot.leadgroup),filesep,'sweetspots',filesep,value,'.sweetspot'], app.sweetspot.resultfig});
            end
            
            app.sweetspot.ID=value;
            app.sweetspot.save;
            
        end

        % Value changed function: PatientsListBox
        function PatientsListBoxValueChanged(app, event)
            [~, ix]=find(ismember(app.PatientsListBox.Items,app.PatientsListBox.Value));
            app.sweetspot.patientselection = ix;
            app.draw;
            app.sweetspot2UI;
        end

        % Value changed function: CovariatesListBox
        function CovariatesListBoxValueChanged(app, event)
            setcovars(app);
            app.draw;
        end

        % Callback function
        function LeftSliderPositiveValueChanged(app, event)
            app.sweetspot.showposamount = [app.RightSliderPositive.Value, app.LeftSliderPositive.Value];
            app.draw;
        end

        % Callback function
        function RightSliderPositiveValueChanged(app, event)
            app.sweetspot.showposamount = [app.RightSliderPositive.Value, app.LeftSliderPositive.Value];
            app.draw;
        end

        % Callback function
        function LeftSliderNegativeValueChanged(app, event)
            app.sweetspot.shownegamount = [app.RightSliderNegative.Value, app.LeftSliderNegative.Value];
            app.draw;
        end

        % Callback function
        function RightSliderNegativeValueChanged(app, event)
            app.sweetspot.shownegamount = [app.RightSliderNegative.Value, app.LeftSliderNegative.Value];
            app.draw;
        end

        % Button pushed function: ColorButtonPositiveBase
        function ColorButtonPositiveBasePushed(app, event)
            c=uisetcolor;
            app.ColorButtonPositiveBase.BackgroundColor=c;
            app.sweetspot.posBaseColor = app.ColorButtonPositiveBase.BackgroundColor;
            app.draw;
        end

        % Button pushed function: ColorButtonNegativeBase
        function ColorButtonNegativeBasePushed(app, event)
            c=uisetcolor;
            app.ColorButtonNegativeBase.BackgroundColor=c;
            app.sweetspot.negBaseColor = app.ColorButtonNegativeBase.BackgroundColor;
            app.draw;
        end

        % Callback function
        function EfieldthresholdSliderValueChanged(app, event)
            if strcmp(app.Efieldmetric.Value,'Mean')
                app.sweetspot.efieldthreshold = app.EfieldthresholdSlider.Value;
            else
                app.sweetspot.efieldthreshold = app.EfieldthresholdSlider.Value*1000;
            end
            app.draw;
        end

        % Callback function
        function ConnectomeDropDownValueChanged(app, event)
            value = app.ConnectomeDropDown.Value;
            app.sweetspot.connectome = value;
            enablerules(app);
            if ~isfield(app.sweetspot.results,ea_conn2connid(app.sweetspot.connectome))
                val=0;
            else
                val=(isfield(app.sweetspot.results.(ea_conn2connid(app.sweetspot.connectome)),ea_method2methodid(app.sweetspot)));
            end
            if val % already calculated
                app.draw;
            end
        end

        % Value changed function: MirrorSidesCheckBox
        function MirrorSidesCheckBoxValueChanged(app, event)
            value = app.MirrorSidesCheckBox.Value;
            app.sweetspot.mirrorsides = app.MirrorSidesCheckBox.Value;
            app.draw;
        end

        % Callback function
        function ShowAmountSliderPositiveValueChanged(app, event)
            value = app.ShowAmountSliderPositive.Value;
            app.LeftSliderPositive.Value = value;
            app.RightSliderPositive.Value = value;
            app.sweetspot.showposamount = [app.RightSliderPositive.Value, app.LeftSliderPositive.Value];
            app.draw;
        end

        % Callback function
        function ShowAmountSliderNegativeValueChanged(app, event)
            value = app.ShowAmountSliderNegative.Value;
            app.LeftSliderNegative.Value = value;
            app.RightSliderNegative.Value = value;
            app.sweetspot.shownegamount = [app.RightSliderNegative.Value, app.LeftSliderNegative.Value];
            app.draw;
        end

        % Callback function
        function ConnthresholdSliderValueChanged(app, event)
            value = app.ConnthresholdSlider.Value;
            app.sweetspot.connthreshold = value;
            app.draw;
        end

        % Button pushed function: RunCVButton
        function RunCVButtonPushed(app, event)
            switch app.StrategyDropDown.Value
                case 'Leave-Nothing-Out (Permutation-Based)'
                    app.sweetspot.customselection = [];
                    [I,Ihat,R0,R1,pperm]=app.sweetspot.lnopb;
                    I = I(:,1);
                    Ihat = Ihat{1};
                    cvs='lnopb';
                    sel=app.sweetspot.patientselection;
                    h1=ea_plothistperm([app.sweetspot.responsevarlabel,' [Permutation-Based Test]'],[R1;R0],{'Unpermuted prediction'},{1});
                    saveas(h1,[fileparts(app.sweetspot.leadgroup),filesep,'sweetspots',filesep,app.sweetspot.ID,'_',app.sweetspot.responsevarlabel,'_',cvs,'_permtest.png']);
                case 'Leave-One-Patient-Out'
                    app.sweetspot.customselection = [];
                    [I,Ihat]=app.sweetspot.loocv;
                    cvs='loocv';
                    sel=app.sweetspot.patientselection;
                case 'Leave-One-Cohort-Out'
                    app.sweetspot.customselection = [];
                    [I,Ihat]=app.sweetspot.lococv;
                    cvs='lococv';
                    sel=app.sweetspot.patientselection;
                case 'k-fold (randomized)'
                    app.sweetspot.customselection = [];
                    [I,Ihat]=app.sweetspot.kfoldcv;
                    cvs='kfoldcv';
                    sel=app.sweetspot.patientselection;
                case 'Custom (Patients)'
                    cvp.NumTestSets = 1;
                    % training and test indices from the items list
                    training = find(ismember(app.TrainOnListBox.Items,app.TrainOnListBox.Value));
                    test = find(ismember(app.PredictListBox.Items,app.PredictListBox.Value));
                    % Patient selected based on the training and test indices
                    app.sweetspot.customselection = unique([training, test]);
                    % Construct cvp struct
                    cvp.training{1} = ismember(app.sweetspot.customselection, training);
                    cvp.test{1} = ismember(app.sweetspot.customselection, test);
                    [I, Ihat]=app.sweetspot.crossval(cvp);
                    cvs = 'custom_pts';
                    sel = test;
                case 'Custom (Cohorts)'
                    cvp.NumTestSets = 1;
                    % training and test indices from the items list
                    training = find(ismember(app.sweetspot.M.patient.group, str2double(app.TrainOnListBox.Value)))';
                    test = find(ismember(app.sweetspot.M.patient.group,str2double(app.PredictListBox.Value)))';
                    % Patient selected based on the training and test indices
                    app.sweetspot.customselection = unique([training, test]);
                    % Construct cvp struct
                    cvp.training{1} = ismember(app.sweetspot.customselection, training);
                    cvp.test{1} = ismember(app.sweetspot.customselection, test);
                    [I, Ihat]=app.sweetspot.crossval(cvp);
                    cvs = 'custom_cohs';
                    sel = test;
                case 'Custom (Subcohorts)'
                    cvp.NumTestSets = 1;
                    % training and test indices from the items list
                    tsets = find(ismember(app.sweetspot.setlabels, app.TrainOnListBox.Value));
                    training=[];
                    for ts=1:length(tsets)
                        training = [training,find(app.sweetspot.setselections{tsets(ts)})];
                    end
                    training=unique(training);
                    tsets = find(ismember(app.sweetspot.setlabels, app.PredictListBox.Value));
                    test=[];
                    for ts=1:length(tsets)
                        test = [test,find(app.sweetspot.setselections{tsets(ts)})];
                    end
                    test=unique(test);
                    % Patient selected based on the training and test indices
                    app.sweetspot.customselection = unique([training, test]);
                    % Construct cvp struct
                    cvp.training{1} = ismember(app.sweetspot.customselection, training);
                    cvp.test{1} = ismember(app.sweetspot.customselection, test);
                    [I, Ihat]=app.sweetspot.crossval(cvp);
                    cvs = 'custom_subcohs';
                    sel = test;
                case 'Custom (Sets)'
                    cvp.NumTestSets = 1;
                    % Generate cvpartition
                    rng(app.sweetspot.rngseed);
                    cvpKfold = cvpartition(length(app.sweetspot.M.patient.list), 'KFold', str2double(app.kEditField.Value));
                    % Calculate training and test indices based on the sets selected and the cvpartitions generated
                    trainSetInd = str2double(app.TrainOnListBox.Value');
                    testSetInd = str2double(app.PredictListBox.Value');
                    training = zeros(size(app.sweetspot.M.patient.list));
                    test = zeros(size(app.sweetspot.M.patient.list));
                    for i=1:length(trainSetInd)
                        training = training | cvpKfold.test(trainSetInd(i));
                    end
                    for i=1:length(testSetInd)
                        test = test | cvpKfold.test(testSetInd(i));
                    end
                    training = find(training);
                    test = find(test);
                    % Patient selected based on the training and test indices
                    app.sweetspot.customselection = unique([training, test]);
                    % Construct cvp struct
                    cvp.training{1} = ismember(app.sweetspot.customselection, training);
                    cvp.test{1} = ismember(app.sweetspot.customselection, test);
                    [I, Ihat]=app.sweetspot.crossval(cvp);
                    cvs = 'custom_sets';
                    sel = test;
                case 'Import Model (.mat)'
                    cvp.NumTestSets = 1;
                    % training and test indices from the items list
                    training = find(ismember(app.TrainOnListBox.Items,app.TrainOnListBox.Value));
                    test = find(ismember(app.PredictListBox.Items,app.PredictListBox.Value));
                    % Patient selected based on the training and test indices
                    app.sweetspot.customselection = unique([training, test]);
                    % Construct cvp struct
                    cvp.training{1} = 1;
                    cvp.test{1} = ismember(app.sweetspot.customselection, test);
                    [I, Ihat]=app.sweetspot.crossval(cvp);
                    cvs = 'custom_pts';
                    sel = test;
                case 'Voxelwise Permutation Test'
                    app.sweetspot.customselection = [];
                    app.isbusy(1, 'Running Permutation Test');
                    drawnow;
                    try
                        permresults = app.sweetspot.permtest(app.sweetspot.Nperm);
                        app.isbusy(0);
                        msgbox(sprintf('Voxelwise permutation test complete (%d permutations).\nSaved to:\n%s', app.sweetspot.Nperm, permresults.savedfile), 'Permutation Test');
                    catch ME
                        app.isbusy(0);
                        uialert(app.UIFigure, ME.message, 'Permutation Test Failed');
                        rethrow(ME);
                    end
                    return;
                case 'Import Voxelwise PermTest (.mat)'
                    test = find(ismember(app.PredictListBox.Items, app.PredictListBox.Value));
                    app.sweetspot.customselection = unique(test);
                    app.isbusy(1, 'Running Out-of-Sample Prediction');
                    drawnow;
                    try
                        predresults = app.sweetspot.predpermtest(app.PermTestModelFile, app.SigModeDropDown.Value);
                        app.isbusy(0);
                        msgbox(sprintf('Out-of-sample prediction complete.\nSaved to:\n%s', predresults.savedfile), 'Voxelwise PermTest Prediction');
                    catch ME
                        app.isbusy(0);
                        uialert(app.UIFigure, ME.message, 'Prediction Failed');
                        rethrow(ME);
                    end
                    return;
            end
            % check if groups exist:
            if length(unique(app.sweetspot.M.patient.group))>1 && app.PosthoccorrectforgroupCheckBox.Value
                Ihat=ea_resid(ea_cohortregressor(app.sweetspot.M.patient.group(sel)),Ihat);
                suffix=' [Corrected for Cohort]';
            else
                suffix='';
            end
            
            
            groupID = app.sweetspot.M.patient.group(sel);
            if length(groupID)==(length(I)/2) % hemiscores have been used
                groupID=[groupID;groupID];
            end
            groupColors = app.sweetspot.M.groups.color(unique(groupID),:);
            groupColors = rgb2hsv(groupColors);
            groupColors(:,2) = groupColors(:,2)+((1-groupColors(:,2))/2);
            groupColors=hsv2rgb(groupColors);
            if exist('pperm', 'var')
                h=ea_corrbox(I,Ihat,pperm,{['Sweetspot prediction ',upper(cvs)],'Empirical',['Sweetspot-Score',suffix]},groupID,[],groupColors);
            else
                I_initialset = I;
                Ihat_initialset_Bend = Ihat;
                save('I_fibfilt_initialset.mat', 'I');
                save('Ihat_initialset_Bend.mat', 'Ihat');
                h=ea_corrbox(I,Ihat,'permutation',{['Sweetspot prediction ',upper(cvs)],'Empirical',['Sweetspot-Score',suffix]},groupID,[],groupColors);
            end
            saveas(h,[fileparts(app.sweetspot.leadgroup),filesep,'sweetspots',filesep,app.sweetspot.ID,'_',app.sweetspot.responsevarlabel,'_',cvs,'.png']);
        end

        % Value changed function: StrategyDropDown
        function StrategyDropDownValueChanged(app, event)
            app.enablerules;
            app.sweetspot2UI;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if exist('event', 'var')
                answer = questdlg("Save Changes?", 'Close Sweetspot Explorer', 'Yes',' No', 'Yes');
            else
                answer = 'Yes';
            end

            if strcmp(answer, 'Yes') && ~isempty(app.sweetspot)
                app.isbusy(1,'saving');
                app.sweetspot.save;
            end

            if iscell(app.sweetspot.drawobject)
                for spot=1:numel(app.sweetspot.drawobject)
                    for ins=1:numel(app.sweetspot.drawobject)
                        try delete(app.sweetspot.drawobject{spot}{ins}.toggleH); end
                        try delete(app.sweetspot.drawobject{spot}{ins}.patchH); end
                        try delete(app.sweetspot.drawobject{spot}{ins}); end
                    end
                end
            end

            if isvalid(app.sweetspot.resultfig) && startsWith(app.sweetspot.resultfig.Name, 'No Patient Selected')
                close(app.sweetspot.resultfig);
            end

            app.isbusy(0);
            delete(app);
        end

        % Callback function
        function EfieldmetricValueChanged(app, event)
            value = app.Efieldmetric.Value;
            app.sweetspot.efieldmetric=value;
            switch lower(value)
                case 'sum'
                    app.EfieldthresholdSlider.Limits=[0.5,5];
                    app.sweetspot.efieldthreshold = 2000; %(/1000) V/mm -> V/m
                case 'mean'
                    app.EfieldthresholdSlider.Limits=[5,1000]; % in V/mm (!)
                    app.sweetspot.efieldthreshold = 100;
                case 'peak'
                    app.EfieldthresholdSlider.Limits=[0.05,2.5];
                    app.sweetspot.efieldthreshold = 200; %(/1000) V/mm -> V/m
                case 'peak 5%'
                    app.EfieldthresholdSlider.Limits=[0.05,2];
                    app.sweetspot.efieldthreshold = 150; %(/1000) V/mm -> V/m
            end
            
            app.enablerules;
            app.sweetspot2UI;
            
            if ~isfield(app.sweetspot.results,ea_conn2connid(app.sweetspot.connectome))
                val=0;
            else
                val=(isfield(app.sweetspot.results.(ea_conn2connid(app.sweetspot.connectome)),ea_method2methodid(app.sweetspot)));
            end
            
            if val % already calculated
                app.draw;
            end
        end

        % Value changed function: kEditField
        function kEditFieldValueChanged(app, event)
            switch app.StrategyDropDown.Value
                case {'Leave-Nothing-Out (Permutation-Based)', 'Voxelwise Permutation Test'}
                    app.sweetspot.Nperm = str2double(app.kEditField.Value);
                case 'k-fold (randomized)'
                    app.sweetspot.kfold = str2double(app.kEditField.Value);
                case 'Custom (Sets)'
                    app.sweetspot.Nsets = str2double(app.kEditField.Value);
                    if str2double(app.kEditField.Value)>1
                        app.TrainOnListBox.Items=sprintfc('%d',[1:app.sweetspot.Nsets]');
                        app.PredictListBox.Items=sprintfc('%d',[1:app.sweetspot.Nsets]');
                    end
            end
        end

        % Value changed function: 
        % CleanVOIfromthefollowingcovariatesCheckBox
        function CleanVOIfromthefollowingcovariatesCheckBoxValueChanged(app, event)
            value = app.CleanVOIfromthefollowingcovariatesCheckBox.Value;
            app.enablerules;
            if ~value
                app.sweetspot.covars = {};
                app.sweetspot.covarlabels = {};
                app.draw;
            else
                setcovars(app);
                app.draw;
            end
            
        end

        % Value changed function: PosthoccorrectforgroupCheckBox
        function PosthoccorrectforgroupCheckBoxValueChanged(app, event)
            value = app.PosthoccorrectforgroupCheckBox.Value;
            app.sweetspot.adjustforgroups=value;
        end

        % Menu selected function: 
        % RefreshInformationfromLeadGroupprojectMenu
        function RefreshInformationfromLeadGroupprojectMenuSelected(app, event)
            app.sweetspot.refreshlg;
            app.sweetspot2UI;
        end

        % Button pushed function: RefreshViewButton
        function RefreshViewButtonPushed(app, event)
            app.draw(1); % draw with force = 1.
        end

        % Value changed function: ConsiderSignificantVoxelsonlyCheckBox
        function ConsiderSignificantVoxelsonlyCheckBoxValueChanged(app, event)
            value = app.ConsiderSignificantVoxelsonlyCheckBox.Value;
            app.sweetspot.showsignificantonly=value;
            app.draw;
        end

        % Button pushed function: ExportasNIfTIButton
        function ExportasNIfTIButtonPushed(app, event)
            [file,path]=uiputfile('*.nii','Save Model as...','model.nii');
            if isnumeric(file)
                return;
            end
            export=app.sweetspot.draw;
            if length(export)==2
                suffx={'_r','_l'};
            else
                suffx={''};
            end
            for exp=1:length(export)
                export{exp}.fname=fullfile(path,[ea_stripext(file),suffx{exp},'.nii']);
                ea_write_nii(export{exp});
            end
            
        end

        % Callback function
        function CPSetsSetsEditFieldValueChanged(app, event)
            value = app.CPSetsSetsEditField.Value;
            app.sweetspot2UI;
        end

        % Value changed function: TrainOnListBox
        function TrainOnListBoxValueChanged(app, event)
            value = app.TrainOnListBox.Value;
            inverse = app.PredictListBox.Items;
            [~, ix] = ismember(value,inverse);
            inverse(ix)=[];
            app.PredictListBox.Value=inverse;
            app.enablerules;
        end

        % Callback function
        function CPCohsTrainOnListBoxValueChanged(app, event)
            value = app.CPCohsTrainOnListBox.Value;
            inverse = app.CPCohsPredictListBox.Items;
            [~, ix] = ismember(value,inverse);
            inverse(ix)=[];
            app.CPCohsPredictListBox.Value=inverse;
        end

        % Callback function
        function CPSetsTrainOnListBoxValueChanged(app, event)
            value = app.CPSetsTrainOnListBox.Value;
            inverse = app.CPSetsPredictListBox.Items;
            [~, ix] = ismember(value,inverse);
            inverse(ix)=[];
            app.CPSetsPredictListBox.Value=inverse;
        end

        % Value changed function: PredictListBox
        function PredictListBoxValueChanged(app, event)
            value = app.PredictListBox.Value;
            app.enablerules;
        end

        % Value changed function: levelEditField
        function levelEditFieldValueChanged(app, event)
            value = app.levelEditField.Value;
            app.sweetspot.alphalevel=(value);
            app.draw;
        end

        % Value changed function: MultTestStrategy
        function MultTestStrategyValueChanged(app, event)
            value = app.MultTestStrategy.Value;
            app.sweetspot.multcompstrategy=value;
            app.draw;
        end

        % Callback function
        function CorrtypeValueChanged(app, event)
            value = app.Corrtype.Value;
            app.sweetspot.corrtype=value;
            switch value
                case {'Skipped Pearson','Skipped Spearman'}
                    app.sweetspot.showsignificantonly=0;
            end
            app.enablerules;
            app.draw;
        end

        % Value changed function: LiveVisualizeCheckBox
        function LiveVisualizeCheckBoxValueChanged(app, event)
            value = app.LiveVisualizeCheckBox.Value;
            app.sweetspot.cvlivevisualize=value;
        end

        % Value changed function: BasePredictiononDropDown
        function BasePredictiononDropDownValueChanged(app, event)
            value = app.BasePredictiononDropDown.Value;
            app.sweetspot.basepredictionon=value;
        end

        % Value changed function: SubcohortsDropDown
        function SubcohortsDropDownValueChanged(app, event)
            value = app.SubcohortsDropDown.Value;
            switch value
                case 'All Patients'
                    app.PatientsListBox.Value=app.PatientsListBox.Items;
                    app.sweetspot.patientselection = 1:length(app.PatientsListBox.Items);
                case 'Create Subcohort from Selection'
                    setname=inputdlg('Specify Set Name','Create New Set');
                    setname=setname{1};
                    setidx=ismember(app.PatientsListBox.Items,app.PatientsListBox.Value);
                    app.sweetspot.setlabels{end+1}=setname;
                    app.sweetspot.setselections{end+1}=setidx;
                case 'Create Subcohort from Inverse Selection'
                    setname=inputdlg('Specify Set Name','Create New Set');
                    setname=setname{1};
                    setidx=~ismember(app.PatientsListBox.Items,app.PatientsListBox.Value);
                    app.sweetspot.setlabels{end+1}=setname;
                    app.sweetspot.setselections{end+1}=setidx;
                case 'Choose...' % do nothing
                otherwise
                    [~,whichset]=ismember(value,app.sweetspot.setlabels);
                    app.PatientsListBox.Value=app.PatientsListBox.Items(app.sweetspot.setselections{whichset});
            end
            % draw:
            [~, ix]=find(ismember(app.PatientsListBox.Items,app.PatientsListBox.Value));
            app.sweetspot.patientselection = ix;
            app.draw;
            % always reset to "Choose..."
            app.enablerules;
        end

        % Menu selected function: SetseedforrandomnumbergeneratorMenu
        function SetseedforrandomnumbergeneratorMenuSelected(app, event)
            rngseed = app.sweetspot.rngseed;
            if ischar(rngseed) && strcmp(rngseed, 'default')
                rngseed = 0;
            end
            rngseed = inputdlg('Input seed for random number generator:', 'Random Seed', [1 40], {num2str(rngseed)});
            if ~isempty(rngseed)
                app.sweetspot.rngseed = str2double(rngseed{1});
            end
        end

        % Value changed function: AutoRefreshCheckBox
        function AutoRefreshCheckBoxValueChanged(app, event)
            value = app.AutoRefreshCheckBox.Value;
            app.sweetspot.autorefresh = value;
            app.enablerules;
        end

        % Value changed function: VTAthresholdVmmEditField
        function VTAthresholdVmmEditFieldValueChanged(app, event)
            value = app.VTAthresholdVmmEditField.Value;
            app.sweetspot.efieldthreshold = value*1000;
            app.enablerules;
            app.draw;
        end

        % Button pushed function: ColorButtonPositivePeak
        function ColorButtonPositivePeakPushed(app, event)
            c=uisetcolor;
            app.ColorButtonPositivePeak.BackgroundColor=c;
            app.sweetspot.posPeakColor = app.ColorButtonPositivePeak.BackgroundColor;
            app.draw;
        end

        % Button pushed function: ColorButtonNegativePeak
        function ColorButtonNegativePeakPushed(app, event)
            c=uisetcolor;
            app.ColorButtonNegativePeak.BackgroundColor=c;
            app.sweetspot.negPeakColor = app.ColorButtonNegativePeak.BackgroundColor;
            app.draw;
        end

        % Value changed function: Corrtype
        function CorrtypeValueChanged2(app, event)
            value = app.Corrtype.Value;
            app.sweetspot.corrtype = value;
            app.draw;
        end

        % Value changed function: ConnthresholdSlider
        function ConnthresholdSliderValueChanged2(app, event)
            value = app.ConnthresholdSlider.Value;
            app.sweetspot.coverthreshold = value;
            
            updatethreshtext(app);
            
            app.draw;
        end

        % Value changing function: ConnthresholdSlider
        function ConnthresholdSliderValueChanging2(app, event)
            changingValue = event.Value;
            app.sweetspot.coverthreshold = changingValue;
            
            updatethreshtext(app);
            
            
        end

        % Value changed function: InspireAnalysisbyDropDown
        function InspireAnalysisbyDropDownValueChanged(app, event)
            value = app.InspireAnalysisbyDropDown.Value;
            
            hint={'While the references mentioned here used the overall strategies shown here,'
                'Please note not everything will be carried out exactly as in the references mentioned.'
                ''
                'For instance, the Butson and Cheung 2011 papers used a completely different VTA model.'
                'The Eisenstein 2014 did not use a VTA model at all but a monotonic decreasing function.'
                'In Reich 2019, a different VTA model came into play and a post-hoc Cluster threshold was applied.'
                'In Eisenstein/Dembek/Elias, a post-hoc permutation statistic was applied.'
                ''
                'While we did our best to unify basic & common concepts used in the referenced papers,'
                'we strongly advise to i) dive into this literature yourself and ii) check with the'
                'manual & documentation of Lead-DBS, what this tool actually does to the data.'
                ''
                'If unsure, please use our Slack help-channel.'
                'Furthermore, we would like to encourage everyone to explore/adapt'
                'their own code & analyses tailored to the studies specific needs.'
                ''
                'In case of code changes that could be useful for others, as well,'
                'consider contributing them via a pull-request on github.'};
            uigetpref('LeadDBS','SweetspotShowInspireHint','Info about presets',hint,'Makes Sense');
            
            switch value
                case 'Butson 2011'
                    app.StatisticalTestDropDown.Value = 'Mean-Image';
                    app.AgainstDropDown.Enable = 'off';
                    app.AgainstDropDown.Value = 'Threshold';
                    app.ImprovementThresholdEditField.Enable = 'on';
                    app.ImprovementThresholdEditFieldLabel.Enable = 'on';
                    app.ImprovementThresholdEditField.Value = 0.5;
                    app.NThresholdinEditField.Enable = 'off';
                    app.NThresholdinEditFieldLabel.Enable = 'off';
                    app.CorrectionDropDown.Value = 'None';
                case 'Cheung 2011'
                    app.StatisticalTestDropDown.Value = 'N-Image';
                    app.AgainstDropDown.Enable = 'off';
                    app.AgainstDropDown.Value = 'Threshold';
                    app.ImprovementThresholdEditField.Enable = 'on';
                    app.ImprovementThresholdEditFieldLabel.Enable = 'on';
                    app.ImprovementThresholdEditField.Value = 0.75;
                    app.NThresholdinEditField.Enable = 'on';
                    app.NThresholdinEditFieldLabel.Enable = 'on';
                    app.NThresholdinEditField.Value = 75;
                    app.CorrectionDropDown.Value = 'None';
                case 'Eisenstein 2014'
                    app.StatisticalTestDropDown.Value = 'T-Test';
                    app.AgainstDropDown.Enable = 'on';
                    app.AgainstDropDown.Value = 'Zero';
                    app.ImprovementThresholdEditField.Enable = 'off';
                    app.ImprovementThresholdEditFieldLabel.Enable = 'off';
                    app.ImprovementThresholdEditField.Value = 0;
                    app.NThresholdinEditField.Enable = 'off';
                    app.NThresholdinEditFieldLabel.Enable = 'off';
                    app.NThresholdinEditField.Value = 0;
                    app.CorrectionDropDown.Value = 'None';
                case 'Reich 2019'
                    app.StatisticalTestDropDown.Value = 'T-Test';
                    app.AgainstDropDown.Enable = 'on';
                    app.AgainstDropDown.Value = 'Average';
                    app.ImprovementThresholdEditField.Enable = 'off';
                    app.ImprovementThresholdEditFieldLabel.Enable = 'off';
                    app.ImprovementThresholdEditField.Value = 0;
                    app.NThresholdinEditField.Enable = 'off';
                    app.NThresholdinEditFieldLabel.Enable = 'off';
                    app.NThresholdinEditField.Value = 0;
                    app.CorrectionDropDown.Value = 'None';
                case 'Dembek 2019'
                    app.StatisticalTestDropDown.Value = 'Wilcoxon-Test';
                    app.AgainstDropDown.Enable = 'on';
                    app.AgainstDropDown.Value = 'Average';
                    app.ImprovementThresholdEditField.Enable = 'off';
                    app.ImprovementThresholdEditFieldLabel.Enable = 'off';
                    app.ImprovementThresholdEditField.Value = 0;
                    app.NThresholdinEditField.Enable = 'off';
                    app.NThresholdinEditFieldLabel.Enable = 'off';
                    app.NThresholdinEditField.Value = 0;
                    app.CorrectionDropDown.Value = 'Amplitude (discrete)';
                case 'Elias 2020'
                    app.StatisticalTestDropDown.Value = 'Wilcoxon-Test';
                    app.AgainstDropDown.Enable = 'on';
                    app.AgainstDropDown.Value = 'Average';
                    app.ImprovementThresholdEditField.Enable = 'off';
                    app.ImprovementThresholdEditFieldLabel.Enable = 'off';
                    app.ImprovementThresholdEditField.Value = 0;
                    app.NThresholdinEditField.Enable = 'off';
                    app.NThresholdinEditFieldLabel.Enable = 'off';
                    app.NThresholdinEditField.Value = 0;
                    app.CorrectionDropDown.Value = 'VTA Size';
                    
            end
            app.sweetspot.stattest = app.StatisticalTestDropDown.Value;
            app.sweetspot.stat0hypothesis = app.AgainstDropDown.Value;
            app.sweetspot.statimpthreshold = (app.ImprovementThresholdEditField.Value);
            app.sweetspot.statNthreshold = (app.NThresholdinEditField.Value);
            app.sweetspot.statamplitudecorrection = app.CorrectionDropDown.Value;
            app.draw;
        end

        % Value changed function: StatisticalTestDropDown
        function StatisticalTestDropDownValueChanged(app, event)
            app.InspireAnalysisbyDropDown.Value = 'Custom';
            switch app.StatisticalTestDropDown.Value
                case 'Mean-Image'
                    app.ImprovementThresholdEditField.Value = 0;
                    app.NThresholdinEditField.Value = 0;
                    app.AgainstDropDown.Value = 'Threshold';
                case 'N-Image'
                    app.ImprovementThresholdEditField.Value = 0;
                    app.NThresholdinEditField.Value = 0;
                    app.AgainstDropDown.Value = 'Threshold';
                otherwise
                    app.AgainstDropDown.Value = 'Zero';
                    app.ImprovementThresholdEditField.Value = 0;
                    app.NThresholdinEditField.Value = 0;
            end


            app.enablerules;
            app.sweetspot.stattest = app.StatisticalTestDropDown.Value;
            app.sweetspot.stat0hypothesis = app.AgainstDropDown.Value;
            app.sweetspot.statimpthreshold = (app.ImprovementThresholdEditField.Value);
            app.sweetspot.statNthreshold = (app.NThresholdinEditField.Value);
            app.sweetspot.statamplitudecorrection = app.CorrectionDropDown.Value;
            app.draw
        end

        % Value changed function: AgainstDropDown
        function AgainstDropDownValueChanged(app, event)
            app.InspireAnalysisbyDropDown.Value = 'Custom';
            app.sweetspot.stat0hypothesis = app.AgainstDropDown.Value;
            app.enablerules;
            app.draw
        end

        % Value changed function: CorrectionDropDown
        function CorrectionDropDownValueChanged(app, event)
            app.InspireAnalysisbyDropDown.Value = 'Custom';
            app.sweetspot.statamplitudecorrection = app.CorrectionDropDown.Value;
            app.enablerules;
            app.draw
        end

        % Value changed function: ImprovementThresholdEditField
        function ImprovementThresholdEditFieldValueChanged(app, event)
            app.InspireAnalysisbyDropDown.Value = 'Custom';
            app.sweetspot.statimpthreshold = app.ImprovementThresholdEditField.Value;
            app.enablerules;
            app.draw
            
        end

        % Value changed function: NThresholdinEditField
        function NThresholdinEditFieldValueChanged(app, event)
            app.InspireAnalysisbyDropDown.Value = 'Custom';
            app.sweetspot.statNthreshold = app.NThresholdinEditField.Value;
            app.enablerules;
            app.draw
        end

        % Value changed function: NormalizeZeroCenterVOIDropDown
        function NormalizeZeroCenterVOIDropDownValueChanged(app, event)
            value = app.NormalizeZeroCenterVOIDropDown.Value;
            app.sweetspot.statnormalization = value;
            app.enablerules;
            app.draw
        end

        % Menu selected function: RecreatevisualizationfigureMenu
        function RecreatevisualizationfigureMenuSelected(app, event)
            if isvalid(app.sweetspot.resultfig)
                close(app.sweetspot.resultfig);
            end
            app.sweetspot.resultfig = ea_mnifigure;
            app.draw(1);
        end

        % Button pushed function: ExportModelmatButton
        function ExportModelmatButtonPushed(app, event)
            [FileName,PathName]=uiputfile('*.mat','File to save the Sweet Spot Model...');
            ExternalModelFile = fullfile(PathName,FileName);
            ea_save_sweetspot_model(app.sweetspot, ExternalModelFile)
        end

        % Button pushed function: LoadModelButton
        function LoadModelButtonPushed(app, event)
            [FileName,PathName]=uigetfile('*.mat','Please Choose a sweet spot model for the selected connectome...');
            if strcmp(string(FileName),'0')
                waitfor(msgbox('The sweet spot model was not chosen!'));
                return;
            end

            app.TrainOnListBox.Items = string(FileName); % this does not update on-the-fly
            app.sweetspot.ExternalModelFile = fullfile(PathName,FileName);
            app.ImportedModelShow.Items = string(FileName);
            value = app.ShowImportedModelCheckBox.Value;
            app.sweetspot.visualizeExternalModel=value;
        end

        % Button pushed function: LoadPermTestModelButton
        function LoadPermTestModelButtonPushed(app, event)
            [FileName,PathName]=uigetfile({'*_permtest_*.mat','Permutation test files (*_permtest_*.mat)'; '*.mat','All MAT-files (*.mat)'}, 'Please choose a voxelwise permutation test file...');
            if strcmp(string(FileName),'0')
                waitfor(msgbox('No permutation test file was chosen!'));
                return;
            end

            app.PermTestModelFile = fullfile(PathName,FileName);
            app.ImportedModelShow.Items = string(FileName);
        end

        % Value changed function: PermuteWithinGroupsCheckBox
        function PermuteWithinGroupsCheckBoxValueChanged(app, event)
            value = app.PermuteWithinGroupsCheckBox.Value;
            app.sweetspot.stratifyPermutationsByGroup = value;
        end

        % Value changed function: SubcohortsDropDown_2
        function SubcohortsDropDown_2ValueChanged(app, event)
            value = app.SubcohortsDropDown_2.Value;
            [~,whichset]=ismember(value,app.sweetspot.setlabels);
            app.PredictListBox.Value=app.PatientsListBox.Items(app.sweetspot.setselections{whichset});

            [~, ix]=find(ismember(app.PatientsListBox.Items,app.PatientsListBox.Value));
            app.sweetspot.patientselection = ix;

            app.enablerules;
        end

        % Value changed function: ShowImportedModelCheckBox
        function ShowImportedModelCheckBoxValueChanged(app, event)
            value = app.ShowImportedModelCheckBox.Value;
            app.sweetspot.visualizeExternalModel=value;

            if value == 1
                app.draw(1)
            else
               app.isbusy(2); 
               app.RefreshViewButton.BackgroundColor = [1,0.8,0.16]; % yellow
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.RefreshInformationfromLeadGroupprojectMenu = uimenu(app.ToolsMenu);
            app.RefreshInformationfromLeadGroupprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @RefreshInformationfromLeadGroupprojectMenuSelected, true);
            app.RefreshInformationfromLeadGroupprojectMenu.Text = 'Refresh Information from Lead-Group project';
            app.RecreatevisualizationfigureMenu = uimenu(app.ToolsMenu);
            app.RecreatevisualizationfigureMenu.MenuSelectedFcn = createCallbackFcn(app, @RecreatevisualizationfigureMenuSelected, true);
            app.RecreatevisualizationfigureMenu.Text = 'Recreate visualization figure';
            app.SetseedforrandomnumbergeneratorMenu = uimenu(app.ToolsMenu);
            app.SetseedforrandomnumbergeneratorMenu.MenuSelectedFcn = createCallbackFcn(app, @SetseedforrandomnumbergeneratorMenuSelected, true);
            app.SetseedforrandomnumbergeneratorMenu.Text = 'Set seed for random number generator';
            app.AnalysisIDEditFieldLabel = uilabel(app.UIFigure);
            app.AnalysisIDEditFieldLabel.Text = 'Analysis ID';
            app.AnalysisIDEditField = uieditfield(app.UIFigure, 'text');
            app.AnalysisIDEditField.ValueChangedFcn = createCallbackFcn(app, @AnalysisIDEditFieldValueChanged, true);
            app.AnalysisIDEditField.ValueChangingFcn = createCallbackFcn(app, @AnalysisIDEditFieldValueChanging, true);
            app.AnalysisIDEditField.Value = 'new_sweetspot_analysis';
            app.LeadGroupPathLabel = uilabel(app.UIFigure);
            app.LeadGroupPathLabel.Text = 'Lead-Group Path';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '<';
            app.InteractiveModelSetupPanel = uipanel(app.UIFigure);
            app.InteractiveModelSetupPanel.Title = 'Interactive Model Setup';
            app.MirrorSidesCheckBox = uicheckbox(app.InteractiveModelSetupPanel);
            app.MirrorSidesCheckBox.ValueChangedFcn = createCallbackFcn(app, @MirrorSidesCheckBoxValueChanged, true);
            app.MirrorSidesCheckBox.Text = 'Mirror Data';
            app.PatientsListBoxLabel = uilabel(app.InteractiveModelSetupPanel);
            app.PatientsListBoxLabel.Text = 'Patients';
            app.PatientsListBox = uilistbox(app.InteractiveModelSetupPanel);
            app.PatientsListBox.Multiselect = 'on';
            app.PatientsListBox.ValueChangedFcn = createCallbackFcn(app, @PatientsListBoxValueChanged, true);
            app.PatientsListBox.Value = {'Item 1'};
            app.AnalysisLevelDropDownLabel = uilabel(app.InteractiveModelSetupPanel);
            app.AnalysisLevelDropDownLabel.Text = 'Analysis Level';
            app.AnalysisLevelDropDown = uidropdown(app.InteractiveModelSetupPanel);
            app.AnalysisLevelDropDown.Items = {'E-Fields', 'VTAs'};
            app.AnalysisLevelDropDown.ValueChangedFcn = createCallbackFcn(app, @AnalysisLevelDropDownValueChanged, true);
            app.AnalysisLevelDropDown.Value = 'VTAs';
            app.SubcohortsDropDownLabel = uilabel(app.InteractiveModelSetupPanel);
            app.SubcohortsDropDownLabel.Text = 'Subcohorts';
            app.SubcohortsDropDown = uidropdown(app.InteractiveModelSetupPanel);
            app.SubcohortsDropDown.Items = {'Choose...', 'All Patients', 'Set from Selection', 'Inverse Set from Selection'};
            app.SubcohortsDropDown.ValueChangedFcn = createCallbackFcn(app, @SubcohortsDropDownValueChanged, true);
            app.SubcohortsDropDown.Value = 'Choose...';
            app.InspireAnalysisbyDropDownLabel = uilabel(app.InteractiveModelSetupPanel);
            app.InspireAnalysisbyDropDownLabel.Text = 'Inspire Analysis by...';
            app.InspireAnalysisbyDropDown = uidropdown(app.InteractiveModelSetupPanel);
            app.InspireAnalysisbyDropDown.Items = {'Butson 2011', 'Cheung 2011', 'Eisenstein 2014', 'Reich 2019', 'Dembek 2019', 'Elias 2020', 'Custom'};
            app.InspireAnalysisbyDropDown.ValueChangedFcn = createCallbackFcn(app, @InspireAnalysisbyDropDownValueChanged, true);
            app.InspireAnalysisbyDropDown.Value = 'Custom';
            app.VTAthresholdVmmEditFieldLabel = uilabel(app.InteractiveModelSetupPanel);
            app.VTAthresholdVmmEditFieldLabel.Text = 'VTA threshold [V/mm]';
            app.VTAthresholdVmmEditField = uieditfield(app.InteractiveModelSetupPanel, 'numeric');
            app.VTAthresholdVmmEditField.ValueChangedFcn = createCallbackFcn(app, @VTAthresholdVmmEditFieldValueChanged, true);
            app.VTAthresholdVmmEditField.Value = 0.2;
            app.Corrtype = uidropdown(app.InteractiveModelSetupPanel);
            app.Corrtype.Items = {'Spearman', 'Pearson', 'Bend', 'Skipped Spearman', 'Skipped Pearson'};
            app.Corrtype.ValueChangedFcn = createCallbackFcn(app, @CorrtypeValueChanged2, true);
            app.Corrtype.Enable = 'off';
            app.Corrtype.Value = 'Spearman';
            app.ConnthresholdSlider = uislider(app.InteractiveModelSetupPanel);
            app.ConnthresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ConnthresholdSliderValueChanged2, true);
            app.ConnthresholdSlider.ValueChangingFcn = createCallbackFcn(app, @ConnthresholdSliderValueChanging2, true);
            app.ConnthresholdSlider.Value = 20;
            app.ConnthresholdLabel = uilabel(app.InteractiveModelSetupPanel);
            app.ConnthresholdLabel.Text = 'Voxels should be covered by at least % of VTAs';
            app.CovariatesListBox = uilistbox(app.InteractiveModelSetupPanel);
            app.CovariatesListBox.Multiselect = 'on';
            app.CovariatesListBox.ValueChangedFcn = createCallbackFcn(app, @CovariatesListBoxValueChanged, true);
            app.CovariatesListBox.Value = {'Item 1'};
            app.CleanVOIfromthefollowingcovariatesCheckBox = uicheckbox(app.InteractiveModelSetupPanel);
            app.CleanVOIfromthefollowingcovariatesCheckBox.ValueChangedFcn = createCallbackFcn(app, @CleanVOIfromthefollowingcovariatesCheckBoxValueChanged, true);
            app.CleanVOIfromthefollowingcovariatesCheckBox.Text = 'Clean VOI from the following covariates';
            app.VariableofInterestVOIDropDownLabel = uilabel(app.InteractiveModelSetupPanel);
            app.VariableofInterestVOIDropDownLabel.Text = 'Variable of Interest (VOI)';
            app.VariableofInterestVOIDropDown = uidropdown(app.InteractiveModelSetupPanel);
            app.VariableofInterestVOIDropDown.ValueChangedFcn = createCallbackFcn(app, @VariableofInterestVOIDropDownValueChanged, true);
            app.StatisticalTestDropDownLabel = uilabel(app.InteractiveModelSetupPanel);
            app.StatisticalTestDropDownLabel.Text = 'Statistical Test';
            app.StatisticalTestDropDown = uidropdown(app.InteractiveModelSetupPanel);
            app.StatisticalTestDropDown.Items = {'Mean-Image', 'Correlations', 'N-Image', 'T-Test', 'Wilcoxon-Test', 'Proportion Test (Binary Var)', 'Binomial Test (Binary Var)'};
            app.StatisticalTestDropDown.ValueChangedFcn = createCallbackFcn(app, @StatisticalTestDropDownValueChanged, true);
            app.StatisticalTestDropDown.Value = 'Mean-Image';
            app.AgainstLabel = uilabel(app.InteractiveModelSetupPanel);
            app.AgainstLabel.Enable = 'off';
            app.AgainstLabel.Text = 'Against';
            app.AgainstDropDown = uidropdown(app.InteractiveModelSetupPanel);
            app.AgainstDropDown.Items = {'Zero', 'Average', 'Threshold'};
            app.AgainstDropDown.ValueChangedFcn = createCallbackFcn(app, @AgainstDropDownValueChanged, true);
            app.AgainstDropDown.Enable = 'off';
            app.AgainstDropDown.Value = 'Threshold';
            app.CorrectionDropDownLabel = uilabel(app.InteractiveModelSetupPanel);
            app.CorrectionDropDownLabel.Text = 'Correction';
            app.CorrectionDropDown = uidropdown(app.InteractiveModelSetupPanel);
            app.CorrectionDropDown.Items = {'None', 'Amplitude', 'VTA Size', 'Amplitude (discrete)', 'Amplitude (regression)'};
            app.CorrectionDropDown.ValueChangedFcn = createCallbackFcn(app, @CorrectionDropDownValueChanged, true);
            app.CorrectionDropDown.Value = 'None';
            app.NThresholdinEditFieldLabel = uilabel(app.InteractiveModelSetupPanel);
            app.NThresholdinEditFieldLabel.Enable = 'off';
            app.NThresholdinEditFieldLabel.Text = 'N-Threshold in %';
            app.NThresholdinEditField = uieditfield(app.InteractiveModelSetupPanel, 'numeric');
            app.NThresholdinEditField.ValueChangedFcn = createCallbackFcn(app, @NThresholdinEditFieldValueChanged, true);
            app.NThresholdinEditField.Enable = 'off';
            app.ImprovementThresholdEditFieldLabel = uilabel(app.InteractiveModelSetupPanel);
            app.ImprovementThresholdEditFieldLabel.Enable = 'off';
            app.ImprovementThresholdEditFieldLabel.Text = 'Improvement Threshold';
            app.ImprovementThresholdEditField = uieditfield(app.InteractiveModelSetupPanel, 'numeric');
            app.ImprovementThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ImprovementThresholdEditFieldValueChanged, true);
            app.ImprovementThresholdEditField.Enable = 'off';
            app.NormalizeZeroCenterVOIDropDownLabel = uilabel(app.InteractiveModelSetupPanel);
            app.NormalizeZeroCenterVOIDropDownLabel.Text = 'Normalize & Zero-Center VOI';
            app.NormalizeZeroCenterVOIDropDown = uidropdown(app.InteractiveModelSetupPanel);
            app.NormalizeZeroCenterVOIDropDown.Items = {'None', 'z-score', 'van Albada 2017'};
            app.NormalizeZeroCenterVOIDropDown.ValueChangedFcn = createCallbackFcn(app, @NormalizeZeroCenterVOIDropDownValueChanged, true);
            app.NormalizeZeroCenterVOIDropDown.Value = 'None';
            app.BusyLampLabel = uilabel(app.UIFigure);
            app.BusyLampLabel.Text = 'Busy';
            app.BusyLamp = uilamp(app.UIFigure);
            app.VisualizationThresholdsPanel = uipanel(app.UIFigure);
            app.VisualizationThresholdsPanel.Title = 'Visualization & Thresholds';
            app.ColorBar = uiaxes(app.VisualizationThresholdsPanel);
            xlabel(app.ColorBar, 'X')
            ylabel(app.ColorBar, 'Y')
            app.ColorButtonPositiveBase = uibutton(app.VisualizationThresholdsPanel, 'push');
            app.ColorButtonPositiveBase.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPositiveBasePushed, true);
            app.ColorButtonPositiveBase.Text = 'Color';
            app.ColorButtonNegativeBase = uibutton(app.VisualizationThresholdsPanel, 'push');
            app.ColorButtonNegativeBase.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonNegativeBasePushed, true);
            app.ColorButtonNegativeBase.Text = 'Color';
            app.ShowPositiveVoxelsCheckBox = uicheckbox(app.VisualizationThresholdsPanel);
            app.ShowPositiveVoxelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowPositiveVoxelsCheckBoxValueChanged, true);
            app.ShowPositiveVoxelsCheckBox.Text = 'Show Positive Voxels';
            app.ShowPositiveVoxelsCheckBox.Value = true;
            app.ShowNegativeVoxelsCheckBox = uicheckbox(app.VisualizationThresholdsPanel);
            app.ShowNegativeVoxelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowNegativeVoxelsCheckBoxValueChanged, true);
            app.ShowNegativeVoxelsCheckBox.Text = 'Show Negative Voxels';
            app.SplitColorbyGroupVariableCheckBox = uicheckbox(app.VisualizationThresholdsPanel);
            app.SplitColorbyGroupVariableCheckBox.ValueChangedFcn = createCallbackFcn(app, @SplitColorbyGroupVariableCheckBoxValueChanged, true);
            app.SplitColorbyGroupVariableCheckBox.Visible = 'off';
            app.SplitColorbyGroupVariableCheckBox.Text = 'Split & Color by Group Variable';
            app.ExportasNIfTIButton = uibutton(app.VisualizationThresholdsPanel, 'push');
            app.ExportasNIfTIButton.ButtonPushedFcn = createCallbackFcn(app, @ExportasNIfTIButtonPushed, true);
            app.ExportasNIfTIButton.Text = 'Export as NIfTI';
            app.ColorButtonNegativePeak = uibutton(app.VisualizationThresholdsPanel, 'push');
            app.ColorButtonNegativePeak.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonNegativePeakPushed, true);
            app.ColorButtonNegativePeak.Text = 'Color';
            app.ColorButtonPositivePeak = uibutton(app.VisualizationThresholdsPanel, 'push');
            app.ColorButtonPositivePeak.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPositivePeakPushed, true);
            app.ColorButtonPositivePeak.Text = 'Color';
            app.ConsiderSignificantVoxelsonlyCheckBox = uicheckbox(app.VisualizationThresholdsPanel);
            app.ConsiderSignificantVoxelsonlyCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConsiderSignificantVoxelsonlyCheckBoxValueChanged, true);
            app.ConsiderSignificantVoxelsonlyCheckBox.Text = 'Consider Significant Voxels only';
            app.ConsiderSignificantVoxelsonlyCheckBox.Value = true;
            app.RefreshViewButton = uibutton(app.VisualizationThresholdsPanel, 'push');
            app.RefreshViewButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshViewButtonPushed, true);
            app.RefreshViewButton.Text = 'Refresh View';
            app.AutoRefreshCheckBox = uicheckbox(app.VisualizationThresholdsPanel);
            app.AutoRefreshCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoRefreshCheckBoxValueChanged, true);
            app.AutoRefreshCheckBox.Text = 'Auto-Refresh';
            app.AutoRefreshCheckBox.Value = true;
            app.levelEditFieldLabel = uilabel(app.VisualizationThresholdsPanel);
            app.levelEditFieldLabel.Text = '𝝰-level';
            app.levelEditField = uieditfield(app.VisualizationThresholdsPanel, 'numeric');
            app.levelEditField.ValueChangedFcn = createCallbackFcn(app, @levelEditFieldValueChanged, true);
            app.levelEditField.Value = 0.05;
            app.MultTestStrategy = uidropdown(app.VisualizationThresholdsPanel);
            app.MultTestStrategy.Items = {'FDR', 'Bonferroni', 'Uncorrected'};
            app.MultTestStrategy.ValueChangedFcn = createCallbackFcn(app, @MultTestStrategyValueChanged, true);
            app.MultTestStrategy.Value = 'Uncorrected';
            app.DataValuesLabel = uilabel(app.VisualizationThresholdsPanel);
            app.DataValuesLabel.Text = 'Average Values (Variable)';
            app.ExportModelmatButton = uibutton(app.VisualizationThresholdsPanel, 'push');
            app.ExportModelmatButton.ButtonPushedFcn = createCallbackFcn(app, @ExportModelmatButtonPushed, true);
            app.ExportModelmatButton.Text = 'Export Model (.mat)';
            app.CrossvalidationPredictionPanel = uipanel(app.UIFigure);
            app.CrossvalidationPredictionPanel.Title = 'Crossvalidation & Prediction';
            app.TrainOnListBoxLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.TrainOnListBoxLabel.Text = 'Train On';
            app.TrainOnListBox = uilistbox(app.CrossvalidationPredictionPanel);
            app.TrainOnListBox.Multiselect = 'on';
            app.TrainOnListBox.ValueChangedFcn = createCallbackFcn(app, @TrainOnListBoxValueChanged, true);
            app.TrainOnListBox.Value = {'Item 1'};
            app.PredictListBoxLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.PredictListBoxLabel.Text = 'Predict';
            app.PredictListBox = uilistbox(app.CrossvalidationPredictionPanel);
            app.PredictListBox.Multiselect = 'on';
            app.PredictListBox.ValueChangedFcn = createCallbackFcn(app, @PredictListBoxValueChanged, true);
            app.PredictListBox.Value = {'Item 1'};
            app.RunCVButton = uibutton(app.CrossvalidationPredictionPanel, 'push');
            app.RunCVButton.ButtonPushedFcn = createCallbackFcn(app, @RunCVButtonPushed, true);
            app.RunCVButton.Text = 'Run';
            app.PosthoccorrectforgroupCheckBox = uicheckbox(app.CrossvalidationPredictionPanel);
            app.PosthoccorrectforgroupCheckBox.ValueChangedFcn = createCallbackFcn(app, @PosthoccorrectforgroupCheckBoxValueChanged, true);
            app.PosthoccorrectforgroupCheckBox.Text = 'Post-hoc correct for group';
            app.StrategyDropDownLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.StrategyDropDownLabel.Text = 'Strategy';
            app.StrategyDropDown = uidropdown(app.CrossvalidationPredictionPanel);
            app.StrategyDropDown.Items = {'Leave-Nothing-Out (Permutation-Based)', 'Leave-One-Patient-Out', 'Leave-One-Cohort-Out', 'k-fold (randomized)', 'Custom (Patients)', 'Custom (Cohorts)', 'Custom (Sets)', 'Custom (Subcohorts)', 'Import Model (.mat)', 'Voxelwise Permutation Test', 'Import Voxelwise PermTest (.mat)'};
            app.StrategyDropDown.ValueChangedFcn = createCallbackFcn(app, @StrategyDropDownValueChanged, true);
            app.StrategyDropDown.Value = 'Leave-Nothing-Out (Permutation-Based)';
            app.kEditFieldLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.kEditFieldLabel.Text = 'k';
            app.kEditField = uieditfield(app.CrossvalidationPredictionPanel, 'text');
            app.kEditField.ValueChangedFcn = createCallbackFcn(app, @kEditFieldValueChanged, true);
            app.LiveVisualizeCheckBox = uicheckbox(app.CrossvalidationPredictionPanel);
            app.LiveVisualizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @LiveVisualizeCheckBoxValueChanged, true);
            app.LiveVisualizeCheckBox.Text = 'Live Visualize';
            app.BasePredictiononDropDownLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.BasePredictiononDropDownLabel.Text = 'Base Prediction on ';
            app.BasePredictiononDropDown = uidropdown(app.CrossvalidationPredictionPanel);
            app.BasePredictiononDropDown.Items = {'Profile of Scores', 'Sum of Scores', 'Mean of Scores', 'Peak of Scores', 'Peak 5% of Scores'};
            app.BasePredictiononDropDown.ValueChangedFcn = createCallbackFcn(app, @BasePredictiononDropDownValueChanged, true);
            app.BasePredictiononDropDown.Value = 'Profile of Scores';
            app.ImportedModelShow = uilistbox(app.CrossvalidationPredictionPanel);
            app.ImportedModelShow.Visible = 'off';
            app.LoadModelButton = uibutton(app.CrossvalidationPredictionPanel, 'push');
            app.LoadModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadModelButtonPushed, true);
            app.LoadModelButton.Visible = 'off';
            app.LoadModelButton.Text = 'Load Model';
            app.LoadPermTestModelButton = uibutton(app.CrossvalidationPredictionPanel, 'push');
            app.LoadPermTestModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPermTestModelButtonPushed, true);
            app.LoadPermTestModelButton.Visible = 'off';
            app.LoadPermTestModelButton.Text = 'Load Voxelwise Permutation Model';
            app.PermuteWithinGroupsCheckBox = uicheckbox(app.CrossvalidationPredictionPanel);
            app.PermuteWithinGroupsCheckBox.ValueChangedFcn = createCallbackFcn(app, @PermuteWithinGroupsCheckBoxValueChanged, true);
            app.PermuteWithinGroupsCheckBox.Visible = 'off';
            app.PermuteWithinGroupsCheckBox.Text = 'Permute Within Groups';
            app.SigModeDropDownLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.SigModeDropDownLabel.Visible = 'off';
            app.SigModeDropDownLabel.Text = 'Sig. Mode';
            app.SigModeDropDown = uidropdown(app.CrossvalidationPredictionPanel);
            app.SigModeDropDown.Items = {'None (raw R everywhere)', 'Independent (each permutation thresholds its own p-values)', 'Fixed (empirical map''s significant voxels, held fixed)'};
            app.SigModeDropDown.ItemsData = {'None', 'Independent', 'Fixed'};
            app.SigModeDropDown.Visible = 'off';
            app.SigModeDropDown.Value = 'None';
            app.SubcohortsDropDown_2Label = uilabel(app.CrossvalidationPredictionPanel);
            app.SubcohortsDropDown_2Label.Visible = 'off';
            app.SubcohortsDropDown_2Label.Text = 'Subcohorts';
            app.SubcohortsDropDown_2 = uidropdown(app.CrossvalidationPredictionPanel);
            app.SubcohortsDropDown_2.ValueChangedFcn = createCallbackFcn(app, @SubcohortsDropDown_2ValueChanged, true);
            app.SubcohortsDropDown_2.Visible = 'off';
            app.ShowImportedModelCheckBox = uicheckbox(app.CrossvalidationPredictionPanel);
            app.ShowImportedModelCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowImportedModelCheckBoxValueChanged, true);
            app.ShowImportedModelCheckBox.Visible = 'off';
            app.ShowImportedModelCheckBox.Text = 'Show Imported Model';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ea_sweetspotexplorer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end